classdef VidClassNetTrain_23122021a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClassificationApp               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        SelectProjectdirButton          matlab.ui.control.Button
        SelectVideodirButton            matlab.ui.control.Button
        CurrentProjectdirEditFieldLabel  matlab.ui.control.Label
        CurrentProjectdirEditField      matlab.ui.control.EditField
        CurrentViddirEditFieldLabel     matlab.ui.control.Label
        CurrentViddirEditField          matlab.ui.control.EditField
        BigframestepEditFieldLabel      matlab.ui.control.Label
        BigframestepEditField           matlab.ui.control.NumericEditField
        RandomframestepfromEditFieldLabel  matlab.ui.control.Label
        RandomframestepfromEditField    matlab.ui.control.NumericEditField
        toEditFieldLabel                matlab.ui.control.Label
        toEditField                     matlab.ui.control.NumericEditField
        FirststackaverageframesfromEditFieldLabel  matlab.ui.control.Label
        FirststackaverageframesfromEditField  matlab.ui.control.NumericEditField
        RerunalreadyclassifiedvidsButton  matlab.ui.control.StateButton
        MovecompletedvidsButton         matlab.ui.control.StateButton
        BatchprocessingsettingsLabel    matlab.ui.control.Label
        PlotsmoothingfactorframenoEditFieldLabel  matlab.ui.control.Label
        PlotsmoothingfactorframenoEditField  matlab.ui.control.NumericEditField
        ReplotexternalfigureButton      matlab.ui.control.Button
        ReplotlabelledframemontageButton  matlab.ui.control.Button
        PlotlinewidthEditFieldLabel     matlab.ui.control.Label
        PlotlinewidthEditField          matlab.ui.control.NumericEditField
        CurrentViddirEditFieldLabel_2   matlab.ui.control.Label
        CurrentViddirEditField_2        matlab.ui.control.EditField
        ThirdstackaverageframesfromEditFieldLabel  matlab.ui.control.Label
        ThirdstackaverageframesfromEditField  matlab.ui.control.NumericEditField
        DuplicatelabelleddatasetwithnewhistoryfutureframesLabel  matlab.ui.control.Label
        SelectProjectdirButton_3        matlab.ui.control.Button
        NewProjectdirEditFieldLabel     matlab.ui.control.Label
        NewProjectdirEditField          matlab.ui.control.EditField
        RunButton                       matlab.ui.control.Button
        AddmissingpicstothefoldersButton  matlab.ui.control.Button
        EditframefilebasedonfolderspngimagesButton  matlab.ui.control.Button
        LoadvideosintomemoryCheckBox    matlab.ui.control.CheckBox
        SkipvideosifanerroroccursduringclassificationCheckBox  matlab.ui.control.CheckBox
        Resizetonetworksize224x224CheckBox  matlab.ui.control.CheckBox
        BatchprocessingsettingsLabel_2  matlab.ui.control.Label
        RGBstackmadeupof3framesthatcanbestacksthemselvesLabel  matlab.ui.control.Label
        SecondstackaverageframesfromEditFieldLabel  matlab.ui.control.Label
        SecondstackaverageframesfromEditField  matlab.ui.control.NumericEditField
        toEditField_2Label              matlab.ui.control.Label
        toEditField_2                   matlab.ui.control.NumericEditField
        toEditField_3Label              matlab.ui.control.Label
        toEditField_3                   matlab.ui.control.NumericEditField
        toEditField_4Label              matlab.ui.control.Label
        toEditField_4                   matlab.ui.control.NumericEditField
        DefaultframerotationEditFieldLabel  matlab.ui.control.Label
        DefaultframerotationEditField   matlab.ui.control.NumericEditField
        DuplicatelabelleddatasetwithnewhistoryfutureframesLabel_2  matlab.ui.control.Label
        DuplicatelabelleddatasetwithnewhistoryfutureframesLabel_3  matlab.ui.control.Label
        LabellingTab                    matlab.ui.container.Tab
        MainPic                         matlab.ui.control.UIAxes
        LAterCurPic                     matlab.ui.control.UIAxes
        PrevCurPiC                      matlab.ui.control.UIAxes
        PrevPic                         matlab.ui.control.UIAxes
        CurrPic                         matlab.ui.control.UIAxes
        LaterPic                        matlab.ui.control.UIAxes
        UIAxes_pxlchange                matlab.ui.control.UIAxes
        ClassTable                      matlab.ui.control.Table
        UndobkspButton                  matlab.ui.control.Button
        LabelspaceButton                matlab.ui.control.Button
        FramesSliderLabel               matlab.ui.control.Label
        FramesSlider                    matlab.ui.control.Slider
        LabelDropDownLabel              matlab.ui.control.Label
        LabelDropDown                   matlab.ui.control.DropDown
        VideoDropDownLabel              matlab.ui.control.Label
        VideoDropDown                   matlab.ui.control.DropDown
        RotateEditFieldLabel            matlab.ui.control.Label
        RotateEditField                 matlab.ui.control.NumericEditField
        FrameSpinnerLabel               matlab.ui.control.Label
        FrameSpinner                    matlab.ui.control.Spinner
        MoveLeftRandom                  matlab.ui.control.Button
        MoveRightRandom                 matlab.ui.control.Button
        MoveLeft10                      matlab.ui.control.Button
        MoveRight10                     matlab.ui.control.Button
        LoadvidButton                   matlab.ui.control.Button
        UseEditFieldLabel               matlab.ui.control.Label
        UseEditField                    matlab.ui.control.EditField
        PriorLabel                      matlab.ui.control.Label
        CurrLabel                       matlab.ui.control.Label
        LaterLabel                      matlab.ui.control.Label
        LabelEditFieldLabel             matlab.ui.control.Label
        LabelEditField                  matlab.ui.control.EditField
        PlotPixelChgButton              matlab.ui.control.StateButton
        LabelEditField_2                matlab.ui.control.EditField
        LabelEditFieldLabel_2           matlab.ui.control.Label
        ClassifysingleframeenterButton  matlab.ui.control.Button
        LoadnetworkButton               matlab.ui.control.Button
        ClassifyperframeButton          matlab.ui.control.StateButton
        PredictionEditField_2Label      matlab.ui.control.Label
        PredictionEditField_2           matlab.ui.control.EditField
        VideoDropDownLabel_2            matlab.ui.control.Label
        VideoDropDown_3                 matlab.ui.control.DropDown
        NextvidButton                   matlab.ui.control.Button
        TrainingTab                     matlab.ui.container.Tab
        orchoosenetworkdefaultisresnet50DropDownLabel  matlab.ui.control.Label
        orchoosenetworkdefaultisresnet50DropDown  matlab.ui.control.DropDown
        TrainButton                     matlab.ui.control.Button
        Trainvalidateratiodefaultis07EditFieldLabel  matlab.ui.control.Label
        Trainvalidateratiodefaultis07EditField  matlab.ui.control.NumericEditField
        MiniBatchSizedefaultis10EditFieldLabel  matlab.ui.control.Label
        MiniBatchSizedefaultis10EditField  matlab.ui.control.NumericEditField
        NumberofEpochsdefaultis20EditFieldLabel  matlab.ui.control.Label
        NumberofEpochsdefaultis20EditField  matlab.ui.control.NumericEditField
        SaveNetButton                   matlab.ui.control.Button
        NamethetrainednetworkEditFieldLabel  matlab.ui.control.Label
        NamethetrainednetworkEditField  matlab.ui.control.EditField
        SelectProjectdirButton_2        matlab.ui.control.Button
        OptionalselectdirfornetButton   matlab.ui.control.Button
        FinalaccuracyEditFieldLabel     matlab.ui.control.Label
        FinalaccuracyEditField          matlab.ui.control.NumericEditField
        LoadpretrainednetworkButton     matlab.ui.control.Button
        NameoftheselectednetEditField_2Label  matlab.ui.control.Label
        NameoftheselectednetEditField   matlab.ui.control.EditField
        ReplacefinalLayerEditField      matlab.ui.control.EditField
        FromlayerLabel                  matlab.ui.control.Label
        FromlayerEditField              matlab.ui.control.NumericEditField
        tolayerEditFieldLabel           matlab.ui.control.Label
        tolayerEditField                matlab.ui.control.NumericEditField
        FreezeinitiallayersButton       matlab.ui.control.StateButton
        ReplacefinalLayerButton         matlab.ui.control.StateButton
        BalanceclassesRecommendedifnumebrsofframesdifferalotCheckBox  matlab.ui.control.CheckBox
        ChecksettingsoutputinMatlabCommandWindowButton  matlab.ui.control.Button
        NumberofaugmentedimageplotsEditFieldLabel  matlab.ui.control.Label
        NumberofaugmentedimageplotsEditField  matlab.ui.control.NumericEditField
        AssesstrainednetButton          matlab.ui.control.Button
        ClassifyingTab                  matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        MainPic_2                       matlab.ui.control.UIAxes
        UIAxes_pxlchange_2              matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        LoadpretrainednetButton_2       matlab.ui.control.Button
        SelectVideodirButton_2          matlab.ui.control.Button
        VideoDropDown_2Label            matlab.ui.control.Label
        VideoDropDown_2                 matlab.ui.control.DropDown
        FramesSlider_2Label             matlab.ui.control.Label
        FramesSlider_2                  matlab.ui.control.Slider
        LoadvidButton_2                 matlab.ui.control.Button
        MoveLeftRandom_2                matlab.ui.control.Button
        MoveRightRandom_2               matlab.ui.control.Button
        MoveLeft10_2                    matlab.ui.control.Button
        MoveRight10_2                   matlab.ui.control.Button
        FrameSpinner_2Label             matlab.ui.control.Label
        FrameSpinner_2                  matlab.ui.control.Spinner
        ClassifysingleframespaceButton  matlab.ui.control.Button
        ClassifywholevidButton          matlab.ui.control.Button
        RotateEditField_2Label          matlab.ui.control.Label
        RotateEditField_2               matlab.ui.control.NumericEditField
        PredictionEditFieldLabel        matlab.ui.control.Label
        PredictionEditField             matlab.ui.control.EditField
        CertaintyEditFieldLabel         matlab.ui.control.Label
        CertaintyEditField              matlab.ui.control.NumericEditField
        ClassifyallvidsinthedirButton   matlab.ui.control.Button
        ClassifyvidswithspecifiedframesButton  matlab.ui.control.Button
        Makeselect_framesButton         matlab.ui.control.Button
        BatchnumEditFieldLabel          matlab.ui.control.Label
        BatchnumEditField               matlab.ui.control.NumericEditField
        EverynthframeEditFieldLabel     matlab.ui.control.Label
        EverynthframeEditField          matlab.ui.control.NumericEditField
        InspectexamplesTab              matlab.ui.container.Tab
        MainPic_5                       matlab.ui.control.UIAxes
        ChooseaclassDropDownLabel       matlab.ui.control.Label
        ChooseaclassDropDown            matlab.ui.control.DropDown
        CertaintythresholdEditFieldLabel  matlab.ui.control.Label
        CertaintythresholdEditField     matlab.ui.control.NumericEditField
        ExampleSpinnerLabel             matlab.ui.control.Label
        ExampleSpinner                  matlab.ui.control.Spinner
        ExampleframesfoundEditFieldLabel  matlab.ui.control.Label
        ExampleframesfoundEditField     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        FindexamplesButton              matlab.ui.control.Button
        FromvidsEditFieldLabel          matlab.ui.control.Label
        FromvidsEditField               matlab.ui.control.NumericEditField
        VideoDropDownLabel_3            matlab.ui.control.Label
        VideoDropDown_4                 matlab.ui.control.DropDown
        LoadvidButton_3                 matlab.ui.control.Button
        FrameSpinner_3Label             matlab.ui.control.Label
        FrameSpinner_3                  matlab.ui.control.Spinner
        ExamplesinthisvidEditFieldLabel  matlab.ui.control.Label
        ExamplesinthisvidEditField      matlab.ui.control.NumericEditField
        DeepDreamTab                    matlab.ui.container.Tab
        MainPic_3                       matlab.ui.control.UIAxes
        LoadpretrainednetButton_3       matlab.ui.control.Button
        AnalysenetworkButton            matlab.ui.control.Button
        SelectlastfullyconnectedlayerSuggestedEditFieldLabel  matlab.ui.control.Label
        SelectlastfullyconnectedlayerSuggestedEditField  matlab.ui.control.NumericEditField
        LabelDropDown_2Label            matlab.ui.control.Label
        LabelDropDown_2                 matlab.ui.control.DropDown
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        NumberofiterationsEditField     matlab.ui.control.NumericEditField
        DreamButton                     matlab.ui.control.Button
        OptionalselectdirsavingthedreamButton  matlab.ui.control.Button
        NamethedreamEditFieldLabel      matlab.ui.control.Label
        NamethedreamEditField           matlab.ui.control.EditField
        SaveDreamButton                 matlab.ui.control.Button
        HumanvsNetTab                   matlab.ui.container.Tab
        MainPic_4                       matlab.ui.control.UIAxes
        NextFrame_HvN                   matlab.ui.control.Button
        LabelsListBoxLabel              matlab.ui.control.Label
        LabelsListBox                   matlab.ui.control.ListBox
        GeneratearandomlistofframesButton  matlab.ui.control.Button
        TesttheNetButton                matlab.ui.control.Button
        InfoTextAreaLabel               matlab.ui.control.Label
        InfoTextArea                    matlab.ui.control.TextArea
        IDEditFieldLabel                matlab.ui.control.Label
        IDEditField                     matlab.ui.control.EditField
        Start_HvN                       matlab.ui.control.Button
        offramestotestEditFieldLabel    matlab.ui.control.Label
        offramestotestEditField         matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        ResultstableButton              matlab.ui.control.Button
        HelpTab                         matlab.ui.container.Tab
        InstallinfoTextAreaLabel        matlab.ui.control.Label
        InstallinfoTextArea             matlab.ui.control.TextArea
        WorkflowTextAreaLabel           matlab.ui.control.Label
        WorkflowTextArea                matlab.ui.control.TextArea
        TipstricksTextAreaLabel         matlab.ui.control.Label
        TipstricksTextArea              matlab.ui.control.TextArea
        KeycommandsTextAreaLabel        matlab.ui.control.Label
        KeycommandsTextArea             matlab.ui.control.TextArea
        StatusEditFieldLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
    end

    
    properties (Access = private)
        frame_mat = []; %set to empty --> for preloading video into memory
        Rand_frame_dataset = []; % Dataset for testing Human vs Net
        Results_human = []; %  for testing Human vs Net
        test_starttime = []; % for testing Human vs Net
        test_frame_idx = []; % for testing Human vs Net
        selectedvid = ''; % video currently selected to be loaded
        vidpathtouse = ''; % completed or todo vid
        videos_avail = ''; % for batch classification
        videos_avail_cmpltd = ''; % completed
%         FreezeinitiallayersButton_constant = 0; % whether to freeze initial layers dueing learning
        Freezeinitiallayers_from_layer = 1; % from which layer to freeze
        Freezeinitiallayers_to_layer = 10; % to which layer to freeze
        framefinalsize = [224,224]; % input size for net
        currentTab = ''; % which tab is active currently
        minibatchsize = 10; %Defaultm mini batch size
        NumofEpochs = 20; %Default num of training epochs
        net = ''; %Default net for training
        MyNetName = 'MyNet'; %Name my network for saving
        MyNetDir = '';
        TrainValRatio = 0.7; %Default ratio for data split up for training vs validation
        vidpath = ''; %dir selected on startup with videos
        vidpath_cmpltd = ''; % dir where completed vids go
        selpath = ''; %dir selected on startup with classification folders
        iFrame = 1; % which frame is to be opened
        obj_name = ''; % name of the video
        rotation_k = 0; % how much to rotate the vid by.
        vid_load_status = 0; %if not loaded cant do other things in training tab
        NumFrames = 2; % what's the max iFrame can be at
        vid = ''; % loaded video
        class_dirs = ''; % info on where to send labelled frames to
        cur_frame_info = ''; % info on what the current frame label is
        confirm = 0; % To confirm labelling
        all_frame_info = {}; % info on all labels completed
        RGB_frame = '';
        I = ''; % save deepdream
        dream_pic_dir = '';
        fig = 0; %Fig handle for later saving
        Plot_pxl_state = 0; % if 1 will plot pxl change when loading video
        pxl_change = ''; % holds pixel change info
        mynet_name = ''; % Holds the name of the pretrained net selected to load tracked info
        classified_data = ''; % Holds the classification data
        reclassify_vids_constant = 0; % if 1 videos will be reclassified even if they were already done
        vid_info = ''; % hold info on all vids with their rotations
        select_frames = []; % specify which frames will be analysed
        Classified_datastruct = []; % This is where all classified data is storred for example inspection
        augimdsTrain = []; % datastore obj for augmented training images
        lgraph = []; % net obj
        options = []; % options for net training
        imdsValidation = []; % datastore for validation images
        augimdsValidation = [];% datastore for validation images
        newcolors = [0 0 0
            1 0.2 0
            0.1 0.6 0
            0 0 0.7
            0.7 0 0.7
            0.6 0.6 0
            0 0.9 0.8
            1.00 0.54 0.00
            0 0.4470 0.7410
            0.8500 0.3250 0.0980
            0.9290 0.6940 0.1250
            0.4940 0.1840 0.5560
            0.4660 0.6740 0.1880
            0.3010 0.7450 0.9330
            0.6350 0.0780 0.1840
            0.83 0.14 0.14
            1.00 0.54 0.00
            0.47 0.25 0.80
            0.25 0.80 0.54]; % figure colours
    end
    methods (Access = private)
        function LoadvidInfo(app)
            temp_info = [];
            filenametemp = fullfile(app.selpath,'\LabelledFrameInfo.mat');
            %             if exist(filenametemp,"file")
            %                 load(filenametemp);
            %                 app.all_frame_info = all_frame_info;
            %             end
            if exist(fullfile(app.vidpath,'\vid_info.mat')) == 2 % vid info from completed classification
                load(fullfile(app.vidpath,'\vid_info.mat'));
                
                if ~isempty(vid_info)
                    %                     vid_info = struct2table(vid_info);
                    tt = struct2table(vid_info);
                    idx = find(strcmpi(tt.Name,app.vid.Name) ==1);
                    
                    if ~isempty(idx) %if an entry for this video exists
                        %                         disp(idx)
                        %                         app.vid_info = struct2table(vid_info);
                        app.vid_info = (vid_info);
                        temp_info = vid_info(idx);
                    end
                end
            end
            
            if isempty(temp_info) && ~isempty(app.all_frame_info)
                % vid info from labelled frames
                t = struct2table(app.all_frame_info);
                [~, idx] = unique(t.Name);
                
                idx = min(find(strcmpi(t.Name,app.vid.Name)==1));
                if ~isempty(idx)
                    temp_info =  app.all_frame_info(idx);
                end
            end
            if isempty(temp_info) || ~isfield(temp_info,'Rotation')
                app.StatusEditField.Value = 'Vid info for this file not found';
                disp('Rotation unkown');
                app.rotation_k = app.DefaultframerotationEditField.Value;
                app.RotateEditField.Value = app.rotation_k;
                app.RotateEditField_2.Value = app.rotation_k;
            else
                app.rotation_k = temp_info.Rotation;
                app.RotateEditField.Value = temp_info.Rotation;
                app.RotateEditField_2.Value = temp_info.Rotation;
                %                         disp('Rotation from labelled frames');
            end
        end
        function LoadVidFun(app,vidname)
            app.frame_mat = [];
            if isempty(app.vidpathtouse)
                app.StatusEditField.Value = '(re-)Select video from a dropdown list';
                drawnow;
            else
                
                app.frame_mat = []; %reset to empty
                tic;
                obj_name = fullfile(app.vidpathtouse,vidname);
                app.obj_name = obj_name;
                app.pxl_change = ''; % clear if data existed before
                app.classified_data = '';
                % Read the vid details
                app.vid = VideoReader(obj_name);
                % Read video
                if app.LoadvideosintomemoryCheckBox.Value == 1
                    [userview,systemview] = memory;
                    if app.Resizetonetworksize224x224CheckBox.Value == 1
                        video_height = app.framefinalsize(1);
                        video_width = app.framefinalsize(2);
                    else
                        video_height = app.vid.Height;
                        video_width = app.vid.Width;
                    end
                    if userview.MaxPossibleArrayBytes < (video_height *  video_width * app.vid.NumFrames)%
                        disp('Video is too big or memory settings too low to be loaded into memory');
                        app.StatusEditField.Value = 'Video is too big or memory settings too low';
                        drawnow;
                    else % video is small enough for pre-loading
                        disp('Loading video into memory');
                        app.frame_mat = zeros(video_height,video_height,app.vid.NumFrames,'uint8');
                        for iFrame = 1:size(app.frame_mat,3)
                            t = read(app.vid, iFrame);
                            t = uint8(squeeze(mean(t,3,'omitnan')));
                            t = imresize(t, [video_height,video_width]);
                            app.frame_mat(:,:,iFrame) = t;
                        end
                    end
                end
                app.vid_load_status = 1;
                app.NumFrames = app.vid.NumFrames;
                
                % Plot pxl change if requested
                if app.Plot_pxl_state == 1
                    app.StatusEditField.Value = 'Ploting pixel change';
                    drawnow;
                    pxl_change_calc_fun(app);
                    pxl_change_plot(app);
                end
                % updateframe
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
                timetemp = toc;
                app.StatusEditField.Value = strjoin({'Done. Took = ',num2str(timetemp),'s'});
                drawnow;
                disp('Vid loading time =');toc;
            end
        end
        function Updatepredfun(app)
            if ~isempty(app.classified_data)
                app.LabelEditField_2.Value = char(app.classified_data.YPred(app.iFrame));
            else
                app.LabelEditField_2.Value = 'Label not found';
            end
        end
        function loadClassifiedDataIfAvail(app)
            
            app.StatusEditField.Value = 'Loading classification/pxl change data';
            drawnow;
            myfolder = strcat(app.vidpath,'\Results\');
            disp(myfolder);
            all_files = dir(fullfile(myfolder,'*.mat'));
            if ~isempty(all_files)
                all_files = struct2table(all_files);
                found_files = find(contains(all_files.name,strrep(app.vid.Name,app.CurrentViddirEditField_2.Value,'')) ==1);
                if length(found_files) >1
                    idx_tt = [];
                    for sus_files = 1:length(found_files)
                        t = all_files.name{found_files(sus_files)};
                        idx = ismember(t,'-');
                        ii=strfind([ 0 idx],[0 1 1 1]);
                        vidname = strcat(t(1:ii-1),app.CurrentViddirEditField_2.Value);
                        if strcmpi(vidname,app.vid.Name)
                            idx_tt(end+1) = sus_files;
                        end
                    end
                    if length(idx_tt) == 1
                        found_files = found_files(idx_tt);
                    end
                end
                if length(found_files) >1
                    disp('More than 1 results file found');
                    app.StatusEditField.Value = 'More than 1 Results file found';
                    drawnow;
                    if ~isempty(app.mynet_name)
                        found_files_with_netname = find(contains(all_files.name(found_files),app.mynet_name) ==1);
                        if ~isempty(found_files_with_netname)
                            app.StatusEditField.Value = 'More than 1 Results file found, using net name data';
                            load(char(fullfile(myfolder,all_files.name(found_files_with_netname))));
                            app.pxl_change = data.pxl_change;
                            app.classified_data = data;
                            drawnow;
                        end
                    else
                        app.StatusEditField.Value = 'For review, load net used to classify Done videos';
                        drawnow;
                    end
                elseif length(found_files) == 1
                    if height(all_files)>1
                        disp(strrep(app.vid.Name,app.CurrentViddirEditField_2.Value,''))
                        load(char(fullfile(myfolder,all_files.name(found_files))));
                        app.pxl_change = data.pxl_change;
                        app.classified_data = data;
                        app.StatusEditField.Value = 'Classification/pxl change data found';
                    elseif height(all_files) == 1 && found_files == 1
                        disp(strrep(app.vid.Name,app.CurrentViddirEditField_2.Value,''))
                        load(char(fullfile(myfolder,all_files.name)));
                        app.pxl_change = data.pxl_change;
                        app.classified_data = data;
                        app.StatusEditField.Value = 'Classification/pxl change data found';
                    end
                    drawnow;
                else
                    app.StatusEditField.Value = 'Failed to find existing classified/pxl change data';
                    drawnow;
                end
            else
                app.StatusEditField.Value = 'Failed to find existing classified/pxl change data';
                drawnow;
                app.LabelEditField_2.Value = 'Label not found';
            end
        end
        function plotProbsBarsfun(app,probs)
            if ~isempty(app.class_dirs)
                %                     x = 1:size(probs,2)
                bar(app.UIAxes,probs);
                xticklabels(app.UIAxes,app.class_dirs.Label);
                xtickangle(app.UIAxes,45)
                %                     xlim(app.UIAxes,[1 (length(app.class_dirs.Label))]);
                %                     set(app.UIAxes,'xtick',[1 5:5:20]);
            else
                bar(app.UIAxes,probs);
            end
        end
        function [learnableLayer,classLayer] = findLayersToReplace(lgraph) %%% from openExample('nnet/TransferLearningUsingGoogLeNetExample')
            
            if ~isa(lgraph,'nnet.cnn.LayerGraph')
                error('Argument must be a LayerGraph object.')
            end
            
            % Get source, destination, and layer names.
            src = string(lgraph.Connections.Source);
            dst = string(lgraph.Connections.Destination);
            layerNames = string({lgraph.Layers.Name}');
            
            % Find the classification layer. The layer graph must have a single
            % classification layer.
            isClassificationLayer = arrayfun(@(l) ...
                (isa(l,'nnet.cnn.layer.ClassificationOutputLayer')|isa(l,'nnet.layer.ClassificationLayer')), ...
                lgraph.Layers);
            
            if sum(isClassificationLayer) ~= 1
                error('Layer graph must have a single classification layer.')
            end
            classLayer = lgraph.Layers(isClassificationLayer);
            
            
            % Traverse the layer graph in reverse starting from the classification
            % layer. If the network branches, throw an error.
            currentLayerIdx = find(isClassificationLayer);
            while true
                
                if numel(currentLayerIdx) ~= 1
                    error('Layer graph must have a single learnable layer preceding the classification layer.')
                end
                
                currentLayerType = class(lgraph.Layers(currentLayerIdx));
                isLearnableLayer = ismember(currentLayerType, ...
                    ['nnet.cnn.layer.FullyConnectedLayer','nnet.cnn.layer.Convolution2DLayer']);
                
                if isLearnableLayer
                    learnableLayer =  lgraph.Layers(currentLayerIdx);
                    return
                end
                
                currentDstIdx = find(layerNames(currentLayerIdx) == dst);
                currentLayerIdx = find(src(currentDstIdx) == layerNames);
                
            end
            
        end
        function [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app)
            % Update displays
            update_frame_spinners_and_sliders(app);
            % Initialise constants/values
            app.LabelEditField.Value = '';
            obj_name = app.obj_name;
            iFrame = app.iFrame;
            rotation_k = app.rotation_k;
            
            %limit frame number
            if iFrame >= app.NumFrames
                app.iFrame = app.NumFrames;
            elseif iFrame<1
                app.iFrame = 1;
            end
            iFrame = app.iFrame;
            % use the loaded vid
            vid = app.vid;
            
            % Read appropriate frames
            % Check if frame history is used for volume input to CNN
            if ~isempty(app.frame_mat)
                % disp('Frame read from memory')
                frame = imrotate(app.frame_mat(:,:, iFrame),app.rotation_k);
            else
                frame = imrotate(read(app.vid, iFrame),app.rotation_k);
            end
            %First frame
            if app.FirststackaverageframesfromEditField.Value~=0
                framemat = []; % Generate history image
                if app.FirststackaverageframesfromEditField.Value <0 && iFrame+app.FirststackaverageframesfromEditField.Value <1
                    first_idx = 1;
                elseif  app.FirststackaverageframesfromEditField.Value >0 && iFrame+app.FirststackaverageframesfromEditField.Value > app.NumFrames
                    first_idx = app.NumFrames;
                else
                    first_idx = iFrame+app.FirststackaverageframesfromEditField.Value;
                end
                if app.toEditField_2.Value <0 && iFrame+app.toEditField_2.Value <1
                    second_idx = 1;
                elseif  app.toEditField_2.Value >0 && iFrame+app.toEditField_2.Value > app.NumFrames
                    second_idx = app.NumFrames;
                else
                    second_idx = iFrame+app.toEditField_2.Value;
                end
                frame_list_stack = [first_idx:second_idx];
                for iiFrame = frame_list_stack
                    clear frametemp
                    if ~isempty(app.frame_mat)
                        % disp('Frame read from memory')
                        frametemp = app.frame_mat(:,:, iiFrame);
                    else
                        frametemp = read(app.vid, iiFrame);
                    end
                    if isempty(framemat)
                        framemat = frametemp;
                    else
                        framemat = cat(4,framemat,frametemp);
                    end
                end
                if isempty(framemat)
                    frame_pre = frame;
                else
                    if length(size(framemat))==4
                        framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                    end
                    frame_pre = imrotate(framemat,app.rotation_k);
                end
            else
                frame_pre = frame; % past is the same as current
            end
            %Third frame
            if app.ThirdstackaverageframesfromEditField.Value~=0
                framemat = []; % Generate history image
                if app.ThirdstackaverageframesfromEditField.Value <0 && iFrame+app.ThirdstackaverageframesfromEditField.Value <1
                    first_idx = 1;
                elseif  app.ThirdstackaverageframesfromEditField.Value >0 && iFrame+app.ThirdstackaverageframesfromEditField.Value > app.NumFrames
                    first_idx = app.NumFrames;
                else
                    first_idx = iFrame+app.ThirdstackaverageframesfromEditField.Value;
                end
                if app.toEditField_4.Value <0 && iFrame+app.toEditField_4.Value <1
                    second_idx = 1;
                elseif  app.toEditField_4.Value >0 && iFrame+app.toEditField_4.Value > app.NumFrames
                    second_idx = app.NumFrames;
                else
                    second_idx = iFrame+app.toEditField_4.Value;
                end
                frame_list_stack = [first_idx:second_idx];
                for iiFrame = frame_list_stack
                    clear frametemp
                    if ~isempty(app.frame_mat)
                        % disp('Frame read from memory')
                        frametemp = app.frame_mat(:,:, iiFrame);
                    else
                        frametemp = read(app.vid, iiFrame);
                    end
                    if isempty(framemat)
                        framemat = frametemp;
                    else
                        framemat = cat(4,framemat,frametemp);
                    end
                end
                if isempty(framemat)
                    frame_post = frame;
                else
                    if length(size(framemat))==4
                        framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                    end
                    frame_post = imrotate(framemat,app.rotation_k);
                end
            else
                frame_post = frame; % past is the same as current
            end
            % middle frame
            if app.SecondstackaverageframesfromEditField.Value~=0
                frame_original = frame;
                framemat = []; % Generate history image
                if app.SecondstackaverageframesfromEditField.Value <0 && iFrame+app.SecondstackaverageframesfromEditField.Value <1
                    first_idx = 1;
                elseif  app.SecondstackaverageframesfromEditField.Value >0 && iFrame+app.SecondstackaverageframesfromEditField.Value > app.NumFrames
                    first_idx = app.NumFrames;
                else
                    first_idx = iFrame+app.SecondstackaverageframesfromEditField.Value;
                end
                if app.toEditField_3.Value <0 && iFrame+app.toEditField_3.Value <1
                    second_idx = 1;
                elseif  app.toEditField_3.Value >0 && iFrame+app.toEditField_3.Value > app.NumFrames
                    second_idx = app.NumFrames;
                else
                    second_idx = iFrame+app.toEditField_3.Value;
                end
                frame_list_stack = [first_idx:second_idx];
                for iiFrame = frame_list_stack
                    clear frametemp
                    if ~isempty(app.frame_mat)
                        % disp('Frame read from memory')
                        frametemp = app.frame_mat(:,:, iiFrame);
                    else
                        frametemp = read(app.vid, iiFrame);
                    end
                    if isempty(framemat)
                        framemat = frametemp;
                    else
                        framemat = cat(4,framemat,frametemp);
                    end
                end
                if isempty(framemat)
                    frame = frame_original;
                else
                    if length(size(framemat))==4
                        framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                    end
                    frame = imrotate(framemat,app.rotation_k);
                end
            else
                %                 frame = frame_original; %  is the same as current
            end
            
            
            % Make sure they are greyscale and put back into 3D
            %             frame = imrotate(read(app.vid, iFrame),app.rotation_k);
            frame_t1 = squeeze(mean(frame,3,'omitnan')); % Current
            frame = uint8(repmat(frame_t1,[1 1 3]));
            frame_t2 = squeeze(mean(frame_pre,3,'omitnan')); % Pre
            frame_pre = uint8(repmat(frame_t2,[1 1 3]));
            frame_t3 = squeeze(mean(frame_post,3,'omitnan')); % Future
            frame_post(:,:,1:3) = uint8(repmat(frame_t3,[1 1 3]));
            temp = frame_t1-frame_t2;
            frame_curpre(:,:,1:3) = uint8(repmat(temp,[1 1 3]));
            temp = frame_t1-frame_t3;
            frame_curpost(:,:,1:3) = uint8(repmat(temp,[1 1 3]));
            RGB_frame = uint8(cat(3,frame_t2,frame_t1,frame_t3));
            %%%% end of Frame creation for CNN
            
            
            % Add frame to params to be later saved if needed
            app.RGB_frame = RGB_frame;
            
        end
        function Reset_cur_frame_info(app)
            %             cur_frame_info.Name = app.vid.Name;
            %             cur_frame_info.iFrame = app.iFrame;
            %             cur_frame_info.Label = app.UseEditField.Value;
            %             cur_frame_info.Rotation = app.rotation_k;
            app.cur_frame_info.Label = app.UseEditField.Value;;
            app.cur_frame_info.Name = app.vid.Name;
            app.cur_frame_info.iFrame = app.iFrame;
            app.cur_frame_info.Rotation = app.rotation_k;
            app.cur_frame_info.Frame_history = [app.FirststackaverageframesfromEditField.Value,app.ThirdstackaverageframesfromEditField.Value];
            %             disp(value);
            
            %             app.cur_frame_info = cur_frame_info;
            %             app.StatusEditField.Value = 'Done';
        end
        function FrameForward(app)
            app.StatusEditField.Value = '+1';
            app.iFrame  = app.iFrame +1;
            [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
            display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
        end
        function FrameBackward(app)
            app.StatusEditField.Value = '-1';
            app.iFrame = app.iFrame -1;
            [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
            display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
        end
        function Remove_last_entry_fun(app)
            all_frame_info = app.all_frame_info;
            if length(all_frame_info)>0
                class_dirs = app.class_dirs;
                cur_frame_info = all_frame_info(end);
                %find correct dir to to delete pic from
                k = find(strcmpi({class_dirs.Label{:}}, char(cur_frame_info.Label))==1); %
                pic_dir = class_dirs.dir{k};
                pic_name = char(strcat(pic_dir,'\',strrep(cur_frame_info.Name,app.CurrentViddirEditField_2.Value,''),num2str(cur_frame_info.iFrame,'%07d'),'.png'));
                if exist(pic_name,"file")
                    delete(pic_name);
                end
                
                if length(all_frame_info) == 1
                    all_frame_info = [];
                else
                    all_frame_info(end) = [];
                end
                app.all_frame_info = all_frame_info;
                app.StatusEditField.Value = char(strjoin({'Frame lbl',num2str(cur_frame_info.iFrame),'deleted'}));
                CountLabels4TableFun(app);
                pathname = app.selpath;
                filenametemp = fullfile(pathname,'\LabelledFrameInfo.mat');
                save(filenametemp,'all_frame_info','-mat');
            end
        end
        function CountLabels4TableFun(app)
            if isempty(app.selpath)
                app.StatusEditField.Value = 'Project dir is not selected!';
                %                 disp('d');
            else
                %                 disp('a');
                all_frame_info = app.all_frame_info;
                class_dirs = app.class_dirs;
                t = app.ClassTable.Data;
                if ~isempty(all_frame_info) && ~isempty(app.vid) && isfield(all_frame_info,'Name') && isprop(app.vid,'Name')
                    %                     disp('b');
                    for i = 1:length(class_dirs.Label)
                        % number of frames of this vid
                        clear temp
                        %                     temp = [find(strcmpi({all_frame_info.Name}, app.vid.Name) == 1), find(strcmpi({all_frame_info.Label}, class_dirs.Label{i}) ==1)];
                        temp = [strcmpi({all_frame_info.Name}, app.vid.Name); strcmpi({all_frame_info.Label}, class_dirs.Label{i})];
                        
                        if isempty(temp)
                            temp = 0;
                        else
                            temp = sum(floor(mean(temp,1)));
                        end
                        t(find(strcmpi(t.Var1, class_dirs.Label{i})==1),2) = {temp};
                        t(find(strcmpi(t.Var1, class_dirs.Label{i})==1),3) = {sum(strcmpi({all_frame_info.Label}, class_dirs.Label{i}))};
                    end
                else
                    %                     disp('c');
                    t = table(class_dirs.Label', zeros(length(class_dirs.Label),1), zeros(length(class_dirs.Label),1));
                end
                app.ClassTable.Data = t;
            end
        end
        function pxl_change_calc_fun(app)
            pxl_change = zeros(1,app.vid.NumFrames);
            if ~isempty(app.frame_mat)
                % disp('Frame read from memory')
                prevframe = app.frame_mat(:,:,1);
            else
                prevframe = read(app.vid,1);
            end
            
            for i = 2:app.vid.NumFrames
                if ~isempty(app.frame_mat)
                    % disp('Frame read from memory')
                    frame = app.frame_mat(:,:, i);
                else
                    frame = read(app.vid,i);
                end
                pxl_change(i) = mean(mean(mean(prevframe-frame)));
                prevframe = frame;
            end
            app.pxl_change = pxl_change;
        end
        function pxl_change_plot(app)
            if ~isempty(app.pxl_change)
                plot (app.UIAxes_pxlchange,app.pxl_change); xline(app.UIAxes_pxlchange,app.iFrame,'r','linewidth',2); xlim(app.UIAxes_pxlchange,[1 app.vid.NumFrames]);
                plot (app.UIAxes_pxlchange_2,app.pxl_change); xline(app.UIAxes_pxlchange_2,app.iFrame,'r','linewidth',2);xlim(app.UIAxes_pxlchange_2,[1 app.vid.NumFrames]);
            else
                plot (app.UIAxes_pxlchange,[0]); xlim(app.UIAxes_pxlchange,[1 app.vid.NumFrames]);%empty
                plot (app.UIAxes_pxlchange_2,[0]); xlim(app.UIAxes_pxlchange_2,[1 app.vid.NumFrames]);
            end
        end
        function plot_classified_vid(app)
            if ~isempty(app.classified_data)
                colororder(app.UIAxes_3,app.newcolors); % Different color style
                colororder(app.UIAxes_2,app.newcolors); % Different color style
                plot(app.UIAxes_3,movmean(app.classified_data.probs,app.PlotsmoothingfactorframenoEditField.Value,1),'Linewidth',app.PlotlinewidthEditField.Value);
                xlim(app.UIAxes_3,[1 app.vid.NumFrames]);
                xline(app.UIAxes_3,app.iFrame,'r','linewidth',2);
                plot(app.UIAxes_2,app.classified_data.probs,'Linewidth',app.PlotlinewidthEditField.Value);
                xlim(app.UIAxes_2,[1 app.vid.NumFrames]);
                xline(app.UIAxes_2,app.iFrame,'r','linewidth',(app.PlotlinewidthEditField.Value+1));
                lgnd = app.class_dirs.Label; %legend
                lgnd(end+1) = {'Current Frame'};
                legend(app.UIAxes_2,lgnd);
                app.PredictionEditField.Value = char(app.classified_data.YPred(app.iFrame));
                app.CertaintyEditField.Value = double(max(app.classified_data.probs(app.iFrame,:)));
                plotProbsBarsfun(app,app.classified_data.probs(app.iFrame,:));
            else
                plot (app.UIAxes_2,[0]); xlim(app.UIAxes_2,[1 app.vid.NumFrames]);%empty
                plot (app.UIAxes_3,[0]); xlim(app.UIAxes_3,[1 app.vid.NumFrames]);%empty
                bar(app.UIAxes,[0]);
            end
        end
        function save_fig_of_classified_vid(app,filenametemp)
            if ~isempty(app.classified_data)
                FigH = figure; colororder(app.newcolors); % Different color style
                subplot(2,1,1); plot(app.classified_data.probs,'Linewidth',app.PlotlinewidthEditField.Value); legend(app.class_dirs.Label);
                xlim([1 app.vid.NumFrames]); title(strjoin({'Raw:',app.vid.name(1:end-4)}));
                subplot(2,1,2); plot(movmean(app.classified_data.probs,app.PlotsmoothingfactorframenoEditField.Value,1),'Linewidth',app.PlotlinewidthEditField.Value);
                xlim([1 app.vid.NumFrames]); title(strjoin({'Movemean by:',num2str(app.PlotsmoothingfactorframenoEditField.Value)}));
                saveas(FigH,filenametemp);
            end
        end
        function loadMyNetFun(app)
            app.StatusEditField.Value = 'Loading My Net';
             % Check if previous dirs known
            filePath = fileparts(mfilename('fullpath'));
            if exist(fullfile(filePath,'classification_app_previously_used_work_dir.mat')) == 0
                [file,dir] = uigetfile;
            else
                load(fullfile(filePath,'classification_app_previously_used_work_dir.mat'));
                if exist('previously_used_work_dir')~=0
                    [file,dir] = uigetfile(previously_used_work_dir);
                else
                   [file,dir] = uigetfile;
                end
            end
            
            app.mynet_name = file(1:end-4);
            app.NameoftheselectednetEditField.Value = app.mynet_name;
            drawnow;
            figure(app.ClassificationApp);
            load(fullfile(dir,file));
            app.net = net;
            app.SelectlastfullyconnectedlayerSuggestedEditField.Value = length(net.Layers)-2; % has to be fully conected layer close the the end
            app.StatusEditField.Value = 'My Net is loaded';
            app.ReplacefinalLayerButton.Value = 0; % to change final layers for training
            app.ReplacefinalLayerEditField.Value = 'No';
        end
        function save_sample_pics_fid(app,filenametemp,data)
            %% Find a pic with highest prob for each of the classes
            horz_num = 3;
            vert_num = ceil(size(data.probs,2)/horz_num);
            FigA = figure;
            for i = 1:size(data.probs,2)
                [p,iFrame] = max(data.probs(:,i)); % Find max prob
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % not sure if this works right now
                % Read appropriate frames
                % Check if frame history is used for volume input to CNN
                if ~isempty(app.frame_mat)
                    % disp('Frame read from memory')
                    frame = imrotate(app.frame_mat(:,:, iFrame),app.rotation_k);
                else
                    frame = imrotate(read(app.vid, iFrame),app.rotation_k);
                end
                if app.FirststackaverageframesfromEditField.Value>0
                    clear framemat % Generate history image
                    for prior_i = 1:app.FirststackaverageframesfromEditField.Value
                        clear frametemp
                        if iFrame-prior_i>0
                            iiFrame = iFrame-prior_i;
                            if ~isempty(app.frame_mat)
                                % disp('Frame read from memory')
                                frametemp = app.frame_mat(:,:, iiFrame);
                            else
                                frametemp = read(app.vid, iiFrame);
                            end
                        else
                            frametemp = nan(size(frame));
                        end
                        if ~(all(all(all(isnan(frametemp)))))
                            if prior_i == 1
                                framemat = frametemp;
                            else
                                framemat = cat(4,framemat,frametemp);
                            end
                        end
                    end
                    if exist('framemat','var')==0
                        frame_pre = frame;
                    else
                        if length(size(framemat))==4
                            framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                        end
                        frame_pre = imrotate(framemat,app.rotation_k);
                    end
                    %     figure; imagesc(frame_pre)
                    
                    
                else
                    %                  frame_pre = nan(size(frame));
                    frame_pre = frame; % past is the same as current
                    %                  frame_curpre = nan(size(frame));
                end
                if app.ThirdstackaverageframesfromEditField.Value>0
                    clear framemat % Generate future image
                    for prior_i = 1:app.ThirdstackaverageframesfromEditField.Value
                        if iFrame+prior_i<app.NumFrames
                            iiFrame = iFrame+prior_i;
                            if ~isempty(app.frame_mat)
                                % disp('Frame read from memory')
                                frametemp = app.frame_mat(:,:, iiFrame);
                            else
                                frametemp = read(app.vid, iiFrame);
                            end
                        else
                            frametemp = nan(size(frame));
                        end
                        if ~(all(all(all(isnan(frametemp)))))
                            if prior_i == 1
                                framemat = frametemp;
                            else
                                framemat = cat(4,framemat,frametemp);
                            end
                        end
                    end
                    if exist('framemat','var')==0
                        frame_post = frame;
                    else
                        if length(size(framemat))==4
                            framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                        end
                        frame_post = imrotate(framemat,app.rotation_k);
                    end
                    
                else
                    %                 frame = imrotate(read(app.vid, iFrame),app.rotation_k);
                    %                 frame_pre = nan(size(frame));
                    %                 frame_post = nan(size(frame));
                    frame_post  = frame; % future is the same as current
                    %                 frame_curpre = nan(size(frame));
                    %                 frame_curpost = nan(size(frame));
                    %                 RGB_frame = frame;
                end
                
                % Make sure they are greyscale and put back into 3D
                %             frame = imrotate(read(app.vid, iFrame),app.rotation_k);
                frame_t1 = squeeze(mean(frame,3,'omitnan')); % Current
                %                 frame = uint8(repmat(frame_t1,[1 1 3]));
                frame_t2 = squeeze(mean(frame_pre,3,'omitnan')); % Pre
                %                 frame_pre = uint8(repmat(frame_t2,[1 1 3]));
                frame_t3 = squeeze(mean(frame_post,3,'omitnan')); % Future
                %                 frame_post(:,:,1:3) = uint8(repmat(frame_t3,[1 1 3]));
                %                 temp = frame_t1-frame_t2;
                %                 frame_curpre(:,:,1:3) = uint8(repmat(temp,[1 1 3]));
                %                 temp = frame_t1-frame_t3;
                %                 frame_curpost(:,:,1:3) = uint8(repmat(temp,[1 1 3]));
                RGB_frame = uint8(cat(3,frame_t2,frame_t1,frame_t3));
                %%%% end of Frame creation for CNN
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                subplot(vert_num,horz_num,i);
                imagesc(RGB_frame);
                set(gca,'xtick',[]);
                set(gca,'xticklabel',[]);
                set(gca,'ytick',[]);
                set(gca,'yticklabel',[]);
                title(strjoin({'Frame =',num2str(iFrame),char(app.class_dirs.Label(i)),num2str(p),'%. Labeleld as -',char(data.YPred(iFrame)),num2str(double(max(data.probs(iFrame,:)))),'%'}));
            end
            try
                suptitle(app.vid.Name(1:end-4));
            end
            saveas(FigA,strcat(filenametemp,'_SamplePics'));
        end
        function check_prev_frame_label(app)
            all_frame_info = app.all_frame_info;
            if exist("all_frame_info") == 0
                app.StatusEditField.Value = 'Please select project dir to proceed';
                %                 disp('1');
            elseif isempty(all_frame_info)
                prev_label = 'Non-Labelled';
                app.LabelEditField.Value = prev_label;
                drawnow;
                %                 disp('2');
            elseif isfield(app.all_frame_info,'Name')
                %                 disp('3');
                app.LabelEditField.Value = '';
                t = struct2table(app.all_frame_info);
                a = find(strcmpi(t.Name, app.vid.Name) == 1 & t.iFrame == app.iFrame );
                if isempty(a)
                    prev_label = 'Non-Labelled';
                    %                     disp('4');
                elseif length(a) > 1
                    for i = 2:length(a)
                        %             imtodel = strcat(app.selpath,'\',t.Label(a(i)),'\',vid.Name(1:end-4),num2str(app.iFrame,'%07d'),'.png')
                        t(a(i),:) = [];
                    end
                    prev_label = char(t.Label(a(1)));
                    %                     disp('5');
                else
                    prev_label = char(t.Label(a(1)));
                end
                %                 disp('6');
                b = table2struct(t);
                app.all_frame_info= b';
                app.LabelEditField.Value = prev_label;
                drawnow;
            end
        end
        function Classify_vid_fun(app)
            if ~isempty(app.net)
                if ~isempty(app.vid) && app.vid_load_status ==1 % && ~isempty(app.class_dirs.Label)
                    tic;
                    % only if not classified before or if reclassify button is pressed
                    if isempty(app.classified_data) || ~isempty(app.classified_data) && app.reclassify_vids_constant == 1
                        
                        %                             tic;
                        app.StatusEditField.Value = strjoin({'Classifying: ',char(app.vid.Name)});
                        drawnow;
                        YPred = categorical(zeros(1,app.vid.NumFrames));
                        probs = zeros(app.vid.NumFrames,length(app.class_dirs.Label));
                        %                             tic;
                        pxl_change = zeros(1,app.vid.NumFrames);
                        
                        
                        if ~isempty(app.select_frames) &&  ~isempty(find(strcmpi({app.select_frames.Name},app.vid.Name) == 1))
                            
                            disp('Will classify predefined frames');
                            select_frames = app.select_frames(find(strcmpi({app.select_frames.Name},app.vid.Name) == 1)).frames_to_classify;
%                             gaps_in_frame_read = 1;
                            %                             disp(select_frames)
                        else
                            select_frames = 1:app.vid.NumFrames;disp('Will classify All frames');
%                             gaps_in_frame_read = 0;
                        end
                        for iFrame_o = 1:length(select_frames)
                            iFrame = select_frames(iFrame_o);
                            app.iFrame = iFrame;
                            [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                            
                            %
                            
                            [YPred_t,probs_t] = classify(app.net,RGB_frame);
                            if length(probs_t) ~= size(probs,2)
                                disp(strjoin({'Error net classes (',num2str(length(probs_t)),')and project classes (',num2str(size(probs,2)),') dont match, check if a correct net was selected'}));
                            end
                            probs_t = probs_t*100;
                            YPred(iFrame) = YPred_t;
                            probs(iFrame,:) = probs_t;
                            
                        end
                        %Savedata
                        app.StatusEditField.Value = strjoin({'saving data: ',char(app.vid.Name)});
                        drawnow;
                        clear data
                        data.YPred = YPred;
                        data.probs = probs;
                        data.pxl_change = pxl_change;
                        data.RotationUsed = app.rotation_k;
                        data.Classes = app.class_dirs.Label;
                        timetemp = toc;
                        data.time_it_took_to_classify = timetemp;
                        data.Name_of_the_net = app.mynet_name;
                        myfolder = strcat(app.vidpath,'\Results\');
                        if ~exist(myfolder,'dir')
                            mkdir(myfolder);
                        end
                        filenametemp = strcat(myfolder,app.vid.name(1:end-4),'---',app.mynet_name);
                        save(filenametemp,'data');
                        app.classified_data = data;
                        % plot the data
                        close all;
                        pxl_change_plot(app);
                        plot_classified_vid(app);
                        save_fig_of_classified_vid(app,filenametemp);
                        save_sample_pics_fid(app,filenametemp,data);
                        
                        % Update vid_info file
                        %load existing video info if available for rotation
                        filenametemp = fullfile(app.vidpath,'\vid_info.mat');
                        vid_info = app.vid_info;
                        
                        if isempty(vid_info) || isempty(find(strcmpi(vertcat({vid_info.Name}),char(app.vid.Name)) == 1))
                            vid_info(end+1).Name = app.vid.Name;
                        end
                        vid_info(find(strcmpi(vertcat({vid_info.Name}),char(app.vid.Name)) == 1)).Classification_complete = 'Yes';
                        vid_info(find(strcmpi(vertcat({vid_info.Name}),char(app.vid.Name)) == 1)).Rotation = app.rotation_k;
                        vid_info(find(strcmpi(vertcat({vid_info.Name}),char(app.vid.Name)) == 1)).Frame_history =  [app.FirststackaverageframesfromEditField.Value,app.ThirdstackaverageframesfromEditField.Value];
                        
                        app.vid_info = vid_info;
                        save(filenametemp,'vid_info','-mat');
                        %Update status
                        app.StatusEditField.Value = strjoin({'Done. Took = ',num2str(floor(timetemp/60)),'min',num2str(timetemp-(floor(timetemp/60)*60)),'s'});
                        drawnow;
                    else
                        app.StatusEditField.Value = strjoin({'Vid already classified. Delete the results file or select "reclasify" button.'});
                        drawnow;
                    end
                    disp('Classify a vid time ='); toc;
                end
            else
                app.StatusEditField.Value = 'Load trained net, vid and select project dir!';
            end
            %             toc;
        end
        function  update_vid_dropdown_fun(app)
            videos = dir(fullfile(app.vidpath,char(strcat('*',app.CurrentViddirEditField_2.Value)))); % with extension code specified
            if isempty(videos)
                app.StatusEditField.Value = 'No to do videos detected in the dir';
            else
                for k = 1 : length(videos)
                    videos_avail{k} = videos(k).name;
                end
                app.videos_avail = videos_avail;
                app.VideoDropDown.Items = videos_avail;
                app.VideoDropDown_2.Items = videos_avail;
                CountLabels4TableFun(app);
                app.StatusEditField.Value = 'To do vids found';
            end
            % Videos in the completed folder
            videos_cmpltd = dir(fullfile(app.vidpath_cmpltd,char(strcat('*',app.CurrentViddirEditField_2.Value))));
            if isempty(videos_cmpltd)
                %                 app.StatusEditField.Value = 'No videos detected in the dir';
            elseif ~isempty(videos)
                for k = 1 : length(videos_cmpltd)
                    videos_avail_cmpltd{k} = videos_cmpltd(k).name;
                end
                app.videos_avail_cmpltd = videos_avail_cmpltd;
                app.VideoDropDown_3.Items = videos_avail_cmpltd;
                drawnow;
                CountLabels4TableFun(app);
                app.selectedvid = videos(1).name;
                app.vidpathtouse = app.vidpath;
            end
            if length(videos) == 1
                app.selectedvid = videos(1).name;
                app.vidpathtouse = app.vidpath;
            end
        end
        
        function NetvsHumanResTable(app)
            allfiles = dir(fullfile(app.selpath,'*.mat'));
            human_files = contains({allfiles.name},'human');
            human_files = allfiles(find(human_files == 1));
            % Master results
            master_results = [];
            % Load human files one by one
            for i = 1:length(human_files)
                load(fullfile(human_files(i).folder,human_files(i).name));
                classes = unique(Results_human.RealLabel);
                % For each class find human accuracy
                master_results(i).Scorer = strcat('Human_',num2str(i));
                master_results(i).ID = human_files(i).name(15:16);
                for ii = 1:length(classes)
                    idx = find(strcmpi({Results_human.RealLabel{:}},classes{ii}) == 1);
                    correct = sum(strcmpi(Results_human.YPred(idx),classes{ii}) == 1);
                    master_results(i).(strcat(strrep(classes{ii},'-',''))) = correct/length(idx) *100;
                end
                test = [];
                for ii = 1:100
                    test(ii) = strcmpi(Results_human.RealLabel{ii},Results_human.YPred{ii});
                end
                master_results(i).Total = sum(test);
            end
            % Calculate mean and sem of humans
            idx_h = i +1;
            master_results(idx_h).Scorer = 'Humans_mean';
            fldnms = fieldnames(master_results);
            for i = 3:length(fieldnames(master_results));
                master_results(idx_h).(fldnms{i}) = mean([master_results.(fldnms{i})]);
            end
            idx_h = idx_h +1;
            master_results(idx_h).Scorer = 'Humans_sem';
            fldnms = fieldnames(master_results);
            for i = 3:length(fieldnames(master_results));
                master_results(idx_h).(fldnms{i}) = nansem_nansuite([master_results.(fldnms{i})]);
            end
            
            % Find all net file results
            net_files = contains({allfiles.name},'_net');
            net_files = allfiles(find(net_files == 1));
            for i = 1:length(net_files)
                load(fullfile(net_files(i).folder,net_files(i).name));
                classes = unique(Results.RealLabel);
                % For each class find human accuracy
                master_results(i+idx_h).Scorer = strcat('Net_',num2str(i));
                %    master_results(i).ID = net_files(i).name(15:16);
                for ii = 1:length(classes)
                    idx = find(strcmpi({Results.RealLabel{:}},classes{ii}) == 1);
                    idx(find(idx == 100)) = [];
                    correct = sum(strcmpi(Results.YPred(idx),classes{ii}) == 1);
                    master_results(i+idx_h).(strcat(strrep(classes{ii},'-',''))) = correct/length(idx) *100;
                end
                test = [];
                for ii = 1:100
                    test(ii) = strcmpi(Results.RealLabel{ii},Results.YPred{ii});
                end
                master_results(i+idx_h).Total = sum(test);
            end
            idx_n = idx_h+i;
            
            % Calculate mean and sem of nets
            master_results(idx_n+1).Scorer = 'Net_mean';
            fldnms = fieldnames(master_results);
            for i = 3:length(fieldnames(master_results));
                t = [master_results.(fldnms{i})];
                t = t((idx_h+1:idx_n));
                master_results(idx_n+1).(fldnms{i}) = mean(t);
            end
            
            master_results(idx_n+2).Scorer = 'Net_sem';
            fldnms = fieldnames(master_results);
            for i = 3:length(fieldnames(master_results));
                t = [master_results.(fldnms{i})];
                t = t((idx_h+1:idx_n));
                master_results(idx_n+2).(fldnms{i}) = nansem_nansuite(t);
            end
            % %%
            fldnms = fieldnames(master_results);
            idx = find(contains({master_results.Scorer},'Humans_mean') ==1);
            idx2 = find(contains({master_results.Scorer},'Net_mean') ==1);
            t = size({master_results.Scorer},2)+1;
            
            % Compare net and human by subtraction
            master_results(t).Scorer = 'Net vs human';
            for i = 3:length(fldnms)
                master_results(t).(fldnms{i}) = master_results(idx2).(fldnms{i}) - master_results(idx).(fldnms{i});
            end
            master_results = struct2table(master_results);
            app.UITable.ColumnName = fldnms;
            app.UITable.Data = table2cell(master_results);
            %             app.UITable.data = master_results;
            drawnow;
            % Save
            filenametemp = fullfile(app.selpath,strcat('Results_NvH','.mat'));
            save(filenametemp,'master_results','-mat');
        end
        
        function PopulateHumVSNet(app)
            if isempty(app.selpath)
                app.StatusEditField.Value = 'Project dir is not selected!';
                %                 disp('d');
            else
                %                 disp('a');
                app.LabelsListBox.Items = app.class_dirs.Label;
                %                 app.LabelsListBox = app.class_dirs;
                
            end
        end
        
        function display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame)
            % Update slider
            app.FramesSlider.Limits = [1 app.NumFrames];
            app.FramesSlider_2.Limits = [1 app.NumFrames];
            
            % display images
            imshow(frame_pre,'Parent',app.PrevPic);
            imshow(frame,'Parent',app.CurrPic);
            imshow(frame_post,'Parent',app.LaterPic);
            imshow(frame_curpre,'Parent',app.PrevCurPiC);
            imshow(frame_curpost,'Parent',app.LAterCurPic);
            imshow(RGB_frame,'Parent',app.MainPic);
            % Update plot
            pxl_change_plot(app);
            % Update prediction from classified data if available
            Updatepredfun(app);
            
            % Update label if prev labelled
            check_prev_frame_label(app);
            
            % display images in Classification tab
            
            imshow(RGB_frame,'Parent',app.MainPic_2);imshow(RGB_frame,'Parent',app.MainPic_5);
            % Update fields and status of Classification tab
            app.RotateEditField_2.Value = app.rotation_k;
            % Classify single frame?
            if app.ClassifyperframeButton.Value == 1
                ClassifySingleFrameFun(app, '');
            end
            % Update classified image fig if available
            plot_classified_vid(app);
            % Update fields and status
%             update_frame_spinners_and_sliders(app);
            app.RotateEditField.Value = app.rotation_k;  
            app.StatusEditField.Value = 'Done';
            if strcmpi(app.currentTab,'Labelling')
                Reset_cur_frame_info(app);
                CountLabels4TableFun(app);
            elseif strcmpi(app.currentTab,'Classifying')
            end
            drawnow;
        end
        
        function files = randReplicateFiles_local(app,files,numDesired)
            n = numel(files);
            ind = randi(n,numDesired,1);
            files = files(ind);
        end
        
        function  train_net_fun(app)
            app.StatusEditField.Value = 'Training starting';
            drawnow;

            app.net = trainNetwork(app.augimdsTrain,app.lgraph,app.options);
            [YPred,probs] = classify(app.net,app.augimdsValidation);
            app.FinalaccuracyEditField.Value = mean(YPred == app.imdsValidation.Labels)*100; %accuracy
            % save training report
%             currentfig = findall(groot, 'Tag', 'NNET_CNN_TRAININGPLOT_UIFIGURE');
%             savefig(currentfig,'test.fig');
            currentfig = findall(groot, 'Type','figure','Tag', 'NNET_CNN_TRAININGPLOT_UIFIGURE');
            save_name = strcat(app.selpath,'\',app.MyNetName,'_nettype_',app.orchoosenetworkdefaultisresnet50DropDown.Value,datestr(now,'_ss_MM_hh-dd-mm-yyyy'));
            try
            savefig(currentfig,save_name)
            catch
                disp('didnt work');
            end
%             savefig(currentfig,strcat(app.selpath,'\',app.MyNetName,'_nettype_',app.orchoosenetworkdefaultisresnet50DropDown.Value,datestr(now,'_ss_MM_hh-dd-mm-yyyy')));
            
            app.StatusEditField.Value = 'Training Complete';
            %         end
        end
        function  assess_net_training_settings_fun(app)
            if isempty(app.net)
                app.StatusEditField.Value = 'Load a net!';
                drawnow;
            else
                app.StatusEditField.Value = 'Setting up the net';
                drawnow;
                net = app.net;
                
                %selecting images and splitting into training vs validation
                imds = imageDatastore(app.selpath, ...
                    'IncludeSubfolders',true, ...
                    'LabelSource','foldernames');
                [imdsTrain,imdsValidation] = splitEachLabel(imds,app.TrainValRatio);
                
                disp('Training dataset before balancing');
                disp(countEachLabel(imdsTrain));
                % %% Image over-sampling for balancing training datasets
                if app.BalanceclassesRecommendedifnumebrsofframesdifferalotCheckBox.Value == 1
                    
                    labels = imdsTrain.Labels;
                    [G,classes] = findgroups(labels);
                    numObservations = splitapply(@numel,labels,G);
                    desiredNumObservationsPerClass = max(numObservations);
                    
                    files = splitapply(@(x){randReplicateFiles_local(app,x,desiredNumObservationsPerClass)},imdsTrain.Files,G);
                    files = vertcat(files{:});
                    labels=[];info=strfind(files,'\');
                    for i=1:numel(files)
                        idx=info{i};
                        dirName=files{i};
                        targetStr=dirName(idx(end-1)+1:idx(end)-1);
                        targetStr2=cellstr(targetStr);
                        labels=[labels;categorical(targetStr2)];
                    end
                    idx_shuffle = randperm(length(labels));
                    imdsTrain.Files = files(idx_shuffle);
                    imdsTrain.Labels  = labels(idx_shuffle);
                    disp('Training dataset after balancing');
                    disp(countEachLabel(imdsTrain));
                    %     disp(countEachLabel(imdsTrain));
                    %     countEachLabel(imdsValidation)
                    %     countEachLabel(imdsTrain)
                end
                
                disp('Validation dataset');
                disp(countEachLabel(imdsValidation));
                
                
                inputSize = net.Layers(1).InputSize;
                if isa(net,'SeriesNetwork') || isa(net,'nnet.cnn.LayerGraph')
                    lgraph = layerGraph(net.Layers);
                else
                    lgraph = layerGraph(net);
                end
                % Replace final layers
                if app.ReplacefinalLayerButton.Value == 1
                    app.StatusEditField.Value = 'Replacing final layers';
                    drawnow;
                    [learnableLayer,classLayer] = findLayersToReplace(lgraph);
                    
                    numClasses = numel(categories(imdsTrain.Labels));
                    
                    if isa(learnableLayer,'nnet.cnn.layer.FullyConnectedLayer')
                        newLearnableLayer = fullyConnectedLayer(numClasses, ...
                            'Name','new_fc', ...
                            'WeightLearnRateFactor',10, ...
                            'BiasLearnRateFactor',10);
                        
                    elseif isa(learnableLayer,'nnet.cnn.layer.Convolution2DLayer')
                        newLearnableLayer = convolution2dLayer(1,numClasses, ...
                            'Name','new_conv', ...
                            'WeightLearnRateFactor',10, ...
                            'BiasLearnRateFactor',10);
                    end
                    
                    lgraph = replaceLayer(lgraph,learnableLayer.Name,newLearnableLayer);
                    newClassLayer = classificationLayer('Name','new_classoutput');
                    lgraph = replaceLayer(lgraph,classLayer.Name,newClassLayer);
                end
                % Freeze initial layers
                if app.FreezeinitiallayersButton.Value == 1
                    layers = lgraph.Layers;
                    connections = lgraph.Connections;
                    
                    layers(app.Freezeinitiallayers_from_layer:app.Freezeinitiallayers_to_layer) = freezeWeights(layers(app.Freezeinitiallayers_from_layer:app.Freezeinitiallayers_to_layer));
                    lgraph = createLgraphUsingConnections(layers,connections);
                end
                % Augment training data
                pixelRange = [-30 30];
                scaleRange = [0.9 1.1];
                Rotation_deg =  [-15 15];
                imageAugmenter = imageDataAugmenter( ...
                    'RandXReflection',true, ...
                    'RandYReflection',true, ...
                    'RandXTranslation',pixelRange, ...
                    'RandYTranslation',pixelRange, ...
                    'RandRotation',Rotation_deg,...
                    'RandXScale',scaleRange, ...
                    'RandYScale',scaleRange);
                augimdsTrain = augmentedImageDatastore(inputSize(1:2),imdsTrain, ...
                    'DataAugmentation',imageAugmenter);
                augimdsValidation = augmentedImageDatastore(inputSize(1:2),imdsValidation);
                
                % set batch and epochs
                miniBatchSize = app.minibatchsize;
                valFrequency = floor(numel(augimdsTrain.Files)/miniBatchSize);
                options = trainingOptions('sgdm', ...
                    'MiniBatchSize',miniBatchSize, ...
                    'MaxEpochs',app.NumofEpochs, ...
                    'InitialLearnRate',3e-4, ...
                    'Shuffle','every-epoch', ...
                    'ValidationData',augimdsValidation, ...
                    'ValidationFrequency',valFrequency, ...
                    'Verbose',false, ...
                    'Plots','training-progress');


                ims = augimdsTrain.read();
                
                numplots = app.NumberofaugmentedimageplotsEditField.Value;
                fig_t =figure('units','normalized','outerposition',[0 0 1 1]);
                tlo = tiledlayout(fig_t,ceil(sqrt(numplots)),ceil(sqrt(numplots)),'TileSpacing','compact');
                for i = 1:numplots
                    ax = nexttile(tlo);
                    imshow(ims{i,1}{:},'Parent',ax);
                    title(strrep(ims{i,2},'_',' '));
                end
                
                disp(strjoin({'Recommended number of batches =',num2str(ceil(augimdsTrain.NumObservations / augimdsTrain.MiniBatchSize))}));
                
                app.augimdsTrain = augimdsTrain;
                app.lgraph = lgraph;
                app.options = options;
                app.imdsValidation = imdsValidation;
                app.augimdsTrain = augimdsTrain;
                app.augimdsValidation = augimdsValidation;
            end
        end
        
        function assess_trained_net(app)

            if isempty(app.net)
                app.StatusEditField.Value = 'Load a net!';
                drawnow;
            elseif ~all(ismember(app.class_dirs.Label, app.net.Layers(end).Classes))
                app.StatusEditField.Value = 'Class labels do not match. Load a net that wass trainined on these classes!';
                drawnow;
            else
                [YPred,probs] = classify(app.net,app.augimdsValidation);
                app.FinalaccuracyEditField.Value = mean(YPred == app.imdsValidation.Labels)*100; %accuracy
                figure('units','normalized','outerposition',[0 0 1 1]);
                confusionchart(app.imdsValidation.Labels,YPred,'Normalization',"row-normalized");
                title('Validation data');

                numImgsToShow = 12;

                % Credit: https://uk.mathworks.com/help/deeplearning/ug/visualize-image-classifications-using-maximal-and-minimal-activating-images.html
                for class_i = 1:length(app.net.Layers(end).Classes)
                    [sortedScores,imgIdx] = findMaxActivatingImages(app,app.imdsValidation,string(app.net.Layers(end).Classes(class_i)),probs,numImgsToShow);

                    figure('units','normalized','outerposition',[0 0 1 1]);
                    plotImages(app,app.imdsValidation,imgIdx,sortedScores,YPred,numImgsToShow)

                    
                    for imgs_i = 1%:numImgsToShow;
                        imageNumber = imgs_i;

                        observation = app.augimdsValidation.readByIndex(imgIdx(imageNumber));
                        img = observation.input{1};

                        label = YPred(imgIdx(imageNumber));
                        score = sortedScores(imageNumber);

                        gradcamMap = gradCAM(app.net,img,label);
                       
                        score = sortedScores(imageNumber);
                        figure('units','normalized','outerposition',[0 0 1 1]);
                        alpha = 0.5;
                        plotGradCAM(app,img,gradcamMap,alpha);
                        sgtitle(string(label)+" (score: "+ max(score)+")")
                    end
                end
            end
        end
        function [sortedScores,imgIdx] = findMaxActivatingImages(app,imds,className,predictedScores,numImgsToShow)
            % Find the predicted scores of the chosen class on all the images of the chosen class
            % (e.g. predicted scores for sushi on all the images of sushi)
            [scoresForChosenClass,imgsOfClassIdxs] = findScoresForChosenClass(app,imds,className,predictedScores);

            % Sort the scores in descending order
            [sortedScores,idx] = sort(scoresForChosenClass,'descend');

            numImgsToShow = min(length(idx),numImgsToShow);
            % Return the indices of only the first few
            imgIdx = imgsOfClassIdxs(idx(1:numImgsToShow));

        end

        function [sortedScores,imgIdx] = findMinActivatingImages(app,imds,className,predictedScores,numImgsToShow)
            % Find the predicted scores of the chosen class on all the images of the chosen class
            % (e.g. predicted scores for sushi on all the images of sushi)
            [scoresForChosenClass,imgsOfClassIdxs] = findScoresForChosenClass(app,imds,className,predictedScores);

            % Sort the scores in ascending order
            [sortedScores,idx] = sort(scoresForChosenClass,'ascend');

            % Return the indices of only the first few
            imgIdx = imgsOfClassIdxs(idx(1:numImgsToShow));

        end

        function [scoresForChosenClass,imgsOfClassIdxs] = findScoresForChosenClass(app,imds,className,predictedScores)
            % Find the index of className (e.g. "sushi" is the 9th class)
            uniqueClasses = unique(imds.Labels);
            chosenClassIdx = string(uniqueClasses) == className;

            % Find the indices in imageDatastore that are images of label "className"
            % (e.g. find all images of class sushi)
            imgsOfClassIdxs = find(imds.Labels == className);

            % Find the predicted scores of the chosen class on all the images of the
            % chosen class
            % (e.g. predicted scores for sushi on all the images of sushi)
            scoresForChosenClass = predictedScores(imgsOfClassIdxs,chosenClassIdx);
        end

        function plotImages(app,imds,imgIdx,sortedScores,predictedClasses,numImgsToShow)
            numImgsToShow = min(length(sortedScores), numImgsToShow);
            for i=1:numImgsToShow
                score = sortedScores(i);
                sortedImgIdx = imgIdx(i);
                predClass = predictedClasses(sortedImgIdx);
                correctClass = imds.Labels(sortedImgIdx);

                imgPath = imds.Files{sortedImgIdx};

                if predClass == correctClass
                    color = "\color{green}";
                else
                    color = "\color{red}";
                end

                predClassTitle = strrep(string(predClass),'_',' ');
                correctClassTitle = strrep(string(correctClass),'_',' ');

                subplot(3,ceil(numImgsToShow./3),i)
                imshow(imread(imgPath));
                title("Predicted: " + color + predClassTitle + "\newline\color{black}Score: " + num2str(score) + "\newlineGround truth: " + correctClassTitle);
            end

        end

        function plotGradCAM(app,img,gradcamMap,alpha)

            subplot(1,2,1)
            imshow(img);

            h = subplot(1,2,2);
            imshow(img)
            hold on;
            imagesc(gradcamMap,'AlphaData',alpha);

            originalSize2 = get(h,'Position');

            colormap jet
            colorbar

            set(h,'Position',originalSize2);
            hold off;
        end
        
        function update_frame_spinners_and_sliders(app)
            if app.iFrame<1
                app.iFrame =1;
            elseif app.iFrame>app.NumFrames
                app.iFrame = app.NumFrames;
            end
                app.FrameSpinner.Value = app.iFrame;
                app.FrameSpinner_2.Value = app.iFrame;
                app.FrameSpinner_3.Value = app.iFrame;
                app.FramesSlider.Value = app.iFrame;
                app.FramesSlider_2.Value = app.iFrame;

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MovecompletedvidsButton.Value = 1;
            app.StatusEditField.Value = 'Hello, pick project directory with labelled subfolders';
        end

        % Button pushed function: SelectProjectdirButton, 
        % SelectProjectdirButton_2
        function SelectProjectDirButtonpressed(app, event)
            app.StatusEditField.Value = 'Selecting dir';
            % Check if previous dirs known
            filePath = fileparts(mfilename('fullpath'));
            if exist(fullfile(filePath,'classification_app_previously_used_work_dir.mat')) == 0
                selpath = uigetdir;
            else
                load(fullfile(filePath,'classification_app_previously_used_work_dir.mat'));
                if exist('previously_used_work_dir')~=0
                    selpath = uigetdir(previously_used_work_dir);
                else
                    selpath = uigetdir;
                end
            end
            if ~isempty(selpath)
                previously_used_work_dir = selpath;
                save(fullfile(filePath,'classification_app_previously_used_work_dir'),'previously_used_work_dir');
            end
            app.CurrentProjectdirEditField.Value = selpath;
            drawnow;
            figure(app.ClassificationApp);
            app.selpath = selpath;
            app.dream_pic_dir = selpath;
            files = dir(selpath);
            files(ismember( {files.name}, {'.', '..'})) = [];  %remove . and ..
            dirFlags = [files.isdir];
            % Extract only those that are directories.
            subFolders = files(dirFlags);
            for k = 1 : length(subFolders)
                class_dirs.dir{k} = char(strcat(subFolders(k).folder,'\',subFolders(k).name));
                class_dirs.Label{k} = subFolders(k).name;
            end
            %load existing label count if available
            filenametemp = fullfile(app.selpath,'\LabelledFrameInfo.mat');
            if exist(filenametemp,"file")
                load(filenametemp);
                app.all_frame_info = all_frame_info;
            end
            % Send class_dirs to app.properties
            classes = class_dirs.Label;
            app.class_dirs = class_dirs;
            % Create a table
            t = table({subFolders.name}', zeros(length(subFolders),1), zeros(length(subFolders),1));
            app.ClassTable.Data = t;
            app.ClassTable.RowName = 'numbered';
            app.LabelDropDown.Items = classes;
            app.LabelDropDown_2.Items = classes;
            app.ChooseaclassDropDown.Items = classes;
            %Update countsg
            CountLabels4TableFun(app);
            % Populate list box in Human vs Net
            PopulateHumVSNet(app);
            app.StatusEditField.Value = 'Project dir selected, if videos are not here, pick a vid dir';
        end

        % Button pushed function: SelectVideodirButton, 
        % SelectVideodirButton_2
        function SelectVidFun(app, event)
            % Check if previous dirs known
            filePath = fileparts(mfilename('fullpath'));
            if exist(fullfile(filePath,'classification_app_previously_used_vid_dir.mat')) == 0
                app.vidpath = uigetdir;
            else
                load(fullfile(filePath,'classification_app_previously_used_vid_dir.mat'));
                if exist('previously_used_vid_dir')~=0
                    app.vidpath = uigetdir(previously_used_vid_dir);
                else
                    app.vidpath = uigetdir;
                end
            end
            if ~isempty(app.vidpath)
                previously_used_vid_dir = app.vidpath;
                save(fullfile(filePath,'classification_app_previously_used_vid_dir'),'previously_used_vid_dir');
            end
            app.CurrentViddirEditField.Value = app.vidpath;
            drawnow; %keep app on top
            figure(app.ClassificationApp);
            app.vidpath_cmpltd = fullfile(app.vidpath,'Completed');
            filecheck = strcat(app.vidpath,'\select_frames.mat');
            if exist(filecheck)
                load(filecheck);
                app.select_frames = select_frames;
            end
            % Videos to run:
            update_vid_dropdown_fun(app);
            
            app.dream_pic_dir = fullfile(app.vidpath,'Dreams');
            if exist(filecheck)
                load(filecheck);
                app.select_frames = select_frames;
            end
        end

        % Button pushed function: LoadvidButton
        function Loadvid(app, event)
            
            if isempty(app.selectedvid)
                vidname = app.VideoDropDown.Value;
                sendtoloadvid(app, event);
            end
            vidname = app.selectedvid;
            
            app.StatusEditField.Value = char(strjoin({'Loading vid:',vidname}));
            drawnow;
            
            LoadVidFun(app, vidname);
            % Update frame info if available
            loadClassifiedDataIfAvail(app);
            % Check if vid.info exists for rotation
            LoadvidInfo(app);
            % Create new files if operating the first time
            filenametemp = fullfile(app.selpath,'\LabelledFrameInfo.mat');
            if exist(filenametemp,'file') == 0
                all_frame_info = app.all_frame_info;
                save(filenametemp,'all_frame_info','-mat');
            end
            filenametemp = fullfile(app.vidpath,'\vid_info.mat');
            if exist(filenametemp,'file') == 0
                vid_info = app.all_frame_info;
                save(filenametemp,'vid_info','-mat');
            end
            
        end

        % Button pushed function: LabelspaceButton
        function ConfirmLabel(app, event)
            if  ~strcmpi(app.cur_frame_info.Label,'None')
                %                 all_frame_info = app.all_frame_info;
                %                 cur_frame_info = app.cur_frame_info;
                %                 RGB_frame = app.RGB_frame;
                %                 class_dirs = app.class_dirs;
                if isempty(app.all_frame_info)
                    app.all_frame_info = app.cur_frame_info;
                else
                    %Check if the frame was already labelled
                    t = struct2table(app.all_frame_info);
                    a = find(strcmpi(t.Name, app.vid.Name) == 1 & t.iFrame == app.iFrame );
                    if isempty(a)
                        app.StatusEditField.Value = 'Frame labelled';
                    else
                        app.StatusEditField.Value = 'Frame RE-labelled!';
                        for i = 1:length(a)
                            imtodel = char(strcat(app.selpath,'\',t.Label(a(i)),'\',app.vid.Name(1:end-4),num2str(app.iFrame,'%07d'),'.png'));
                            delete(imtodel); % delete all existing
                            t(a(i),:) = []; % delete all existing entries in all_frame_info
                        end
                    end
                    b = table2struct(t);
                    app.all_frame_info= b';
                    app.all_frame_info(end+1) = app.cur_frame_info;
                    drawnow;
                    
                end
                if length(app.all_frame_info) == 1
                    pathname = app.selpath;
                    filenametemp = fullfile(pathname,'LabelledFrameInfo.mat');
                    all_frame_info = app.all_frame_info;
                    save(filenametemp,'all_frame_info','-mat');
                    % Save info about vid for tracking
                    vid_info = app.all_frame_info;
                    filenametemp = fullfile(app.vidpath,'\vid_info.mat');
                    save(filenametemp,'vid_info','-mat');
                else
                    pathname = app.selpath;
                    filenametemp = fullfile(pathname,'LabelledFrameInfo.mat');
                    all_frame_info = app.all_frame_info;
                    save(filenametemp,'all_frame_info','-mat');
                    % Save info about vid for tracking
                    t = struct2table(app.all_frame_info);
                    [~, idx] = unique(t.Name);
                    vid_info = app.all_frame_info(idx);
                    filenametemp = fullfile(app.vidpath,'\vid_info.mat');
                    save(filenametemp,'vid_info','-mat');
                end
                %find correct dir to save pic in
                k = find(strcmpi({app.class_dirs.Label{:}}, app.cur_frame_info.Label)==1); %
                %                 disp(app.cur_frame_info.Label);
                pic_dir = app.class_dirs.dir{k};
                pic_name = char(strcat(pic_dir,'\',strrep(app.cur_frame_info.Name,app.CurrentViddirEditField_2.Value,''),num2str(app.cur_frame_info.iFrame,'%07d'),'.png'));
                imwrite(app.RGB_frame,pic_name);
                %                 Reset_cur_frame_info(app);
                app.LabelEditField.Value = 'Labelled';
                CountLabels4TableFun(app);
                drawnow;
                %     else
                %         disp('here');
            end
        end

        % Value changed function: FramesSlider
        function Updateslider(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = 'Slider selecting new frame';
                drawnow;
                app.iFrame = round(app.FramesSlider.Value);
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
            else
                value = 1;
                app.iFrame = value;
            end
        end

        % Value changed function: LabelDropDown
        function Labelpressed(app, event)
            value = app.LabelDropDown.Value;
            app.LabelDropDown_2.Value = value;
            app.ChooseaclassDropDown.Value = value;
            app.cur_frame_info.Label = value;
            app.cur_frame_info.Name = app.vid.Name;
            app.cur_frame_info.iFrame = app.iFrame;
            app.cur_frame_info.Rotation = app.rotation_k;
            app.cur_frame_info.Frame_history = [app.FirststackaverageframesfromEditField.Value,app.ThirdstackaverageframesfromEditField.Value];
            disp(value);
            app.UseEditField.Value = value;
        end

        % Value changed function: VideoDropDown
        function sendtoloadvid(app, event)
            app.VideoDropDown_2.Value = app.VideoDropDown.Value;
            app.selectedvid = app.VideoDropDown.Value;
            app.vidpathtouse = app.vidpath;
            Loadvid(app, event);
        end

        % Value changed function: RotateEditField
        function SetImRot(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = 'Rotating';
                drawnow;
                app.rotation_k = app.RotateEditField.Value;
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
            else
                app.rotation_k = app.DefaultframerotationEditField.Value;
            end
        end

        % Value changed function: FrameSpinner
        function framenumber(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = 'Changing frame';
                drawnow;
                app.iFrame = app.FrameSpinner.Value;
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
            else
                app.iFrame = 1;
            end
        end

        % Button pushed function: MoveLeftRandom, MoveLeftRandom_2
        function RandomLeft(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = '<< Rand';
                drawnow;
                app.iFrame = app.iFrame - round(app.RandomframestepfromEditField.Value + (app.toEditField.Value-app.RandomframestepfromEditField.Value).*rand(1));
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
            else
                app.iFrame = 1;
            end
        end

        % Button pushed function: MoveRightRandom, MoveRightRandom_2
        function RandomRight(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = 'Rand >>';
                drawnow;
                app.iFrame = app.iFrame + round(app.RandomframestepfromEditField.Value + (app.toEditField.Value-app.RandomframestepfromEditField.Value).*rand(1));        
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
            else
                app.iFrame = 1;
            end
        end

        % Button pushed function: MoveLeft10, MoveLeft10_2
        function Left10(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = '<< Big step';
                drawnow;
                value = app.iFrame;
                value = value - app.BigframestepEditField.Value;
                app.iFrame = value;
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
            else
                app.iFrame = 1;
            end
        end

        % Button pushed function: MoveRight10, MoveRight10_2
        function Right10(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = 'Big step >>';
                drawnow;
                app.iFrame = app.iFrame+ app.BigframestepEditField.Value;
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
            else
                app.iFrame = 1;
            end
        end

        % Value changed function: 
        % orchoosenetworkdefaultisresnet50DropDown
        function NetSelect(app, event)
            %             value = app.orchoosenetworkdefaultisresnet50DropDown.Value;
            value = char(app.orchoosenetworkdefaultisresnet50DropDown.Value)
            if strcmpi(value,'resnet18')
                net = resnet18;
                app.mynet_name = 'resnet18';
                app.NameoftheselectednetEditField.Value = app.mynet_name;
            elseif strcmpi(value,'resnet50')
                net = resnet50;
                app.mynet_name = 'resnet50';
                app.NameoftheselectednetEditField.Value = app.mynet_name;
            elseif strcmpi(value,'resnet101')
                net = resnet101;
                app.mynet_name = 'resnet101';
                app.NameoftheselectednetEditField.Value = app.mynet_name;
            end
            drawnow;
            app.net = net;
            app.ReplacefinalLayerButton.Value = 1;
            app.framefinalsize = net.Layers(1).InputSize(1:2);
            app.ReplacefinalLayerEditField.Value = 'Yes';
        end

        % Button pushed function: TrainButton
        function TrainNet(app, event)
            assess_net_training_settings_fun(app);
            train_net_fun(app);
            assess_trained_net(app);
        end

        % Value changed function: 
        % Trainvalidateratiodefaultis07EditField
        function trainValRatio(app, event)
            value = app.Trainvalidateratiodefaultis07EditField.Value;
            app.TrainValRatio = value;
        end

        % Value changed function: MiniBatchSizedefaultis10EditField
        function minibatchsizefun(app, event)
            value = app.MiniBatchSizedefaultis10EditField.Value;
            app.minibatchsize = value;
        end

        % Value changed function: NumberofEpochsdefaultis20EditField
        function Numofepochsfun(app, event)
            value = app.NumberofEpochsdefaultis20EditField.Value;
            app.NumofEpochs  = value;
        end

        % Key press function: ClassificationApp
        function ClassificationAppKeyPress(app, event)
            key = event.Key;
            if strcmpi(key,'rightarrow') || strcmpi(key,'d') %1 forward
                FrameForward(app);
            end
            if strcmpi(key,'leftarrow') || strcmpi(key,'a') %1 backward
                FrameBackward(app);
            end
            if strcmpi(key,'uparrow') || strcmpi(key,'w') % 10 forward
                Right10(app,'uparrow');
            end
            if strcmpi(key,'downarrow') || strcmpi(key,'s') % 10 backward
                Left10(app,'downarrow');
            end
            if strcmpi(key,'m') || strcmpi(key,'e') % rand forward
                RandomRight(app,'m');
            end
            if strcmpi(key,'n') || strcmpi(key,'q') % rand backward
                RandomLeft(app,'n');
            end
            if strcmpi(key,'space') % confirm
                if strcmpi(app.currentTab,'Labelling')
                    ConfirmLabel(app, 'space');
                elseif strcmpi(app.currentTab,'Classifying')
                    ClassifySingleFrameFun(app, event);
                elseif strcmpi(app.currentTab,'Human vs Net')
                    NextFrame_HvNButtonPushed(app);
                end
            end
            if strcmpi(key,'return') % confirm
                ClassifySingleFrameFun(app, event);
            end
            if strcmpi(key,'backspace') % undo
                Remove_last_entry_fun(app);
            end
            
            % Dynamically create number buttons for labels
            if  ~isempty(app.class_dirs)
                for i = 1:min(length(app.class_dirs.Label),9)
                    if strcmpi(key, num2str(i)) || strcmpi(key, strcat('numpad',num2str(i))) % numpad or normal number
                        value = app.class_dirs.Label{i}; % which value this number represents
                        app.LabelDropDown.Value = value;
                        app.LabelDropDown_2.Value = value;
                        app.LabelsListBox.Value = value;
                        app.ChooseaclassDropDown = value;
                        if app.vid_load_status == 1
                            Labelpressed(app, event);
                        elseif strcmpi(app.currentTab,'Human vs Net')
                            
                        else
                            app.StatusEditField.Value = 'Please load vid';
                            drawnow;
                        end
                    end
                end
            end
            %                         disp(key);
        end

        % Size changed function: TrainingTab
        function TrainingTabfun(app, event)
            
        end

        % Callback function
        function LabellingTabFun(app, event)
            
        end

        % Callback function
        function SettingsTabfun(app, event)
            
        end

        % Value changed function: NamethetrainednetworkEditField
        function MyNetNameFun(app, event)
            app.MyNetName = app.NamethetrainednetworkEditField.Value;
        end

        % Button pushed function: SaveNetButton
        function SaveMyNetFun(app, event)
            net = app.net;
            if ~isempty(net)
                if isempty(app.MyNetDir)
                    MyNetDir = app.selpath;
                else
                    MyNetDir = app.MyNetDir;
                end
                savepath = char(strcat(MyNetDir,'\',app.MyNetName,'.mat'));
                save(savepath,'net','-mat');
                app.StatusEditField.Value = 'Network saved';
            else
                app.StatusEditField.Value = 'Train net first';
            end
        end

        % Button pushed function: OptionalselectdirfornetButton
        function SelectdirforNet(app, event)
            app.MyNetDir = uigetdir;
            drawnow;
            figure(app.ClassificationApp);
        end

        % Button pushed function: LoadpretrainednetButton_2
        function LoadMyNetFun(app, event)
            loadMyNetFun(app);
        end

        % Value changed function: VideoDropDown_2
        function VideoDropDown_2ValueChanged(app, event)
            app.VideoDropDown.Value = app.VideoDropDown_2.Value;
            app.selectedvid = app.VideoDropDown_2.Value;
            app.vidpathtouse = app.vidpath;
            LoadvidButton_2Pushed(app, event);
        end

        % Button pushed function: LoadvidButton_2
        function LoadvidButton_2Pushed(app, event)
            %             vidname = app.VideoDropDown_2.Value;
            %             vidname = app.selectedvid;
            %             app.StatusEditField.Value = char(strjoin({'Loading vid:',vidname}));
            %             drawnow;
            %             LoadVidFun(app, vidname);
            %
            
            if isempty(app.selectedvid)
                vidname = app.VideoDropDown.Value;
                sendtoloadvid(app, event);
            end
            vidname = app.selectedvid;
            
            app.StatusEditField.Value = char(strjoin({'Loading vid:',vidname}));
            drawnow;
            
            LoadVidFun(app, vidname);
            % Update frame info if available
            loadClassifiedDataIfAvail(app);
            % Check if vid.info exists for rotation
            LoadvidInfo(app);
            % Create new files if operating the first time
            filenametemp = fullfile(app.selpath,'\LabelledFrameInfo.mat');
            if exist(filenametemp,'file') == 0
                all_frame_info = app.all_frame_info;
                save(filenametemp,'all_frame_info','-mat');
            end
            filenametemp = fullfile(app.vidpath,'\vid_info.mat');
            if exist(filenametemp,'file') == 0
                vid_info = app.all_frame_info;
                save(filenametemp,'vid_info','-mat');
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab.Title;
            app.currentTab = selectedTab;
            if strcmpi(app.currentTab,'Labelling')
                app.StatusEditField.Value = 'Please make sure project dir is selected!';
                %                  app.StatusEditField.Value = 'Attention: make sure project dir is selected to see labels!';
            elseif strcmpi(app.currentTab,'Classifying')
                
            elseif strcmpi(app.currentTab,'Training')
                app.StatusEditField.Value = 'Make sure training data is ready and selected in project dir';
            elseif strcmpi(app.currentTab,'Settings')
                app.StatusEditField.Value = 'Select project and video dirs';
            elseif strcmpi(app.currentTab,'Inspect examples')
                app.StatusEditField.Value = 'Looking for examples';
                %Check if results are available
                result_files = [];
                if exist(fullfile(app.vidpath,'Results'),'dir') ~= 0
                    result_files =dir(fullfile(app.vidpath,'Results','*.mat'));
                end
                if ~isempty(result_files)
                    disp(strjoin({num2str(length({result_files.name})),'result files found'}));
                    probs = []; frames = [];
                    for file_i = 1:length({result_files.name})
                        load(fullfile(result_files(file_i).folder,result_files(file_i).name))
                        if all(strcmpi(app.class_dirs.Label,data.Classes))
                            name_t = strrep(strrep(strrep(strrep(result_files(file_i).name,'(','--'),')','---'),'.mat',''),'-','_');
                            probs.(name_t) = data.probs;
                            frames.(name_t) = 1:length(data.probs);
                            names.(name_t) = result_files(file_i).name;
                        else
                            disp('Classes for this video do not match, reclassify and try again');
                        end
                        
                    end
                    app.Classified_datastruct.probs = probs;
                    app.Classified_datastruct.frames = frames;
                    app.Classified_datastruct.names = names;
                else
                    disp('No results found');
                    app.StatusEditField.Value = 'No results found';
                end
                %                 app.StatusEditField.Value = 'Select project and video dirs';
            end
            drawnow;
            %             app.StatusEditField.Value = char(strcat('Current tab is',char(selectedTab)));
        end

        % Callback function
        function SelectVideodirButton_2Pushed(app, event)
            vidpath = app.selpath;
            videos = dir(fullfile(vidpath,char(strcat('*',app.CurrentViddirEditField_2.Value))));
            if isempty(videos)
                vidpath = uigetdir;
                drawnow; %keep app on top
                figure(app.ClassificationApp);
            end
            app.vidpath = vidpath;
            videos = dir(fullfile(vidpath,char(strcat('*',app.CurrentViddirEditField_2.Value))));
            if isempty(videos)
                app.StatusEditField.Value = 'No videos detected in the dir';
            else
                for k = 1 : length(videos)
                    videos_avail{k} = videos(k).name;
                end
                app.VideoDropDown.Items = videos_avail;
                app.VideoDropDown_2.Items = videos_avail;
                %load existing label count if available
                filenametemp = fullfile(app.selpath,'\LabelledFrameInfo.mat');
                if exist(filenametemp,"file")
                    load(filenametemp);
                    app.all_frame_info = all_frame_info;
                end
                app.CurrentViddirEditField.Value = vidpath;
                app.StatusEditField.Value = 'Vid dir accepted, proceed to Labelling';
            end
        end

        % Value changed function: RotateEditField_2
        function SetImRot2(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = 'Rotating';
                value = app.RotateEditField_2.Value;
                app.rotation_k = value;
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
            else
                app.rotation_k = app.DefaultframerotationEditField.Value;
            end
        end

        % Value changed function: FrameSpinner_2
        function framenumber2(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = 'Changing frame';
                app.iFrame = app.FrameSpinner_2.Value;
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
            else
                app.iFrame = 1;
            end
        end

        % Value changed function: FramesSlider_2
        function Updateslider2(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = 'Slider selecting new frame';
                
                app.iFrame  = round(app.FramesSlider_2.Value);
                update_frame_spinners_and_sliders(app);
                

            else
                value = 1;
                app.iFrame = value;
            end
        end

        % Button pushed function: ClassifysingleframeenterButton, 
        % ClassifysingleframespaceButton
        function ClassifySingleFrameFun(app, event)
            RGB_frame = app.RGB_frame;
            net = app.net;
            if ~isempty(net) && ~isempty(RGB_frame)
                app.StatusEditField.Value = 'Classifying selected frame';
                frame = imresize(RGB_frame, app.framefinalsize);
                [YPred,probs] = classify(net,frame);
                probs = probs*100;
                app.PredictionEditField.Value = char(YPred);
                app.PredictionEditField_2.Value = char(YPred);
                app.CertaintyEditField.Value = double(max(probs));
                plotProbsBarsfun(app,probs);
                timetemp = toc;
                app.StatusEditField.Value = strjoin({'Done. Took = ',num2str(timetemp),'s'});
            else
                app.StatusEditField.Value = 'Load Trained net!';
            end
        end

        % Button pushed function: ClassifywholevidButton
        function ClassifyVid(app, event)
            if isempty(app.vid)
                Loadvid(app, event);
            end
            if app.SkipvideosifanerroroccursduringclassificationCheckBox.Value == 1
                try
                    Classify_vid_fun(app);
                end
            else
                Classify_vid_fun(app);
            end
            %Move completed video
            if app.MovecompletedvidsButton.Value == 1
                myfolder = strcat(app.vidpath,'\Completed'); %where completed videos will be moved to
                if exist(myfolder,"dir") == 0
                    mkdir(myfolder);
                end
                %                 disp(app.vidpath);disp(app.vid.Name); disp(fullfile(myfolder,char(app.vid.Name)));
                if exist(fullfile(app.vidpath,app.vid.Name)) ~=0 % load vid
                    movefile(fullfile(app.vidpath,char(app.vid.Name)),fullfile(myfolder,char(app.vid.Name)));
                    update_vid_dropdown_fun(app);
                end
            end
        end

        % Button pushed function: ClassifyallvidsinthedirButton
        function ClassifyallvidsinthedirButtonPushed(app, event)
            vids_to_classify = app.videos_avail;
            app.vidpathtouse = app.vidpath;
            if app.reclassify_vids_constant ==1
                completed_vids = app.videos_avail_cmpltd;
            end
            for i = 1:length(vids_to_classify)
                vidname = char(vids_to_classify{i});
                app.StatusEditField.Value = strjoin({'Reading unclassified video',num2str(i),'/',num2str(length(vids_to_classify)),vidname});
                %Loadvid
                LoadVidFun(app,vidname);
                % Classify
                if app.SkipvideosifanerroroccursduringclassificationCheckBox.Value == 1
                    try
                        Classify_vid_fun(app);
                    end
                else
                    Classify_vid_fun(app);
                end
                %Move completed video
                if app.MovecompletedvidsButton.Value == 1
                    myfolder = strcat(app.vidpath,'\Completed'); %where completed videos will be moved to
                    if exist(myfolder,"dir") == 0
                        mkdir(myfolder);
                    end
                    %                     disp(app.vidpath);disp(vidname); disp(fullfile(myfolder,char(vidname)));
                    movefile(fullfile(app.vidpath,char(vidname)),fullfile(myfolder,char(vidname)));
                    update_vid_dropdown_fun(app);
                end
            end
            if app.reclassify_vids_constant == 1
                vids_to_classify = completed_vids;
                app.vidpathtouse = app.vidpath_cmpltd;
            else
                vids_to_classify = [];
            end
            if ~isempty(vids_to_classify)
                for i = 1:length(vids_to_classify)
                    vidname = char(app.videos_avail{1});
                    app.StatusEditField.Value = strjoin({'Reading unclassified video',num2str(i),'/',num2str(length(vids_to_classify)),vidname});
                    %Loadvid
                    LoadVidFun(app,vidname);
                    % Classify
                    if app.SkipvideosifanerroroccursduringclassificationCheckBox.Value == 1
                        try
                            Classify_vid_fun(app);
                        end
                    else
                        Classify_vid_fun(app);
                    end
                end
            end
        end

        % Value changed function: PlotPixelChgButton
        function plt_pxl_toggle_button(app, event)
            app.Plot_pxl_state = app.PlotPixelChgButton.Value;
            if ~isempty(app.vid) && app.Plot_pxl_state == 1
                app.StatusEditField.Value = 'Ploting pixel change';
                drawnow;
                pxl_change_calc_fun(app);
                pxl_change_plot(app);
            end
        end

        % Callback function
        function Load_classified_data(app, event)
            
        end

        % Value changed function: RerunalreadyclassifiedvidsButton
        function RerunalreadyclassifiedvidsButtonValueChanged(app, event)
            app.reclassify_vids_constant = app.RerunalreadyclassifiedvidsButton.Value;
        end

        % Value changed function: MovecompletedvidsButton
        function MovecompletedvidsButtonValueChanged(app, event)
            
        end

        % Button pushed function: UndobkspButton
        function UndobkspButtonPushed(app, event)
            Remove_last_entry_fun(app);
        end

        % Button pushed function: ReplotexternalfigureButton
        function ReplotexternalfigureButtonPushed(app, event)
            if ~isempty(app.classified_data)
                myfolder = strcat(app.vidpath,'\Results\');
                if ~exist(myfolder,'dir')
                    mkdir(myfolder);
                end
                filenametemp = strcat(myfolder,app.vid.name(1:end-4),app.mynet_name);
                save_fig_of_classified_vid(app,filenametemp);
            end
        end

        % Button pushed function: ReplotlabelledframemontageButton
        function ReplotlabelledframemontageButtonPushed(app, event)
            if ~isempty(app.classified_data)
                myfolder = strcat(app.vidpath,'\Results\');
                if ~exist(myfolder,'dir')
                    mkdir(myfolder);
                end
                filenametemp = strcat(myfolder,app.vid.name(1:end-4),app.mynet_name);
                save_sample_pics_fid(app,filenametemp,app.classified_data);
            end
        end

        % Button pushed function: LoadnetworkButton, 
        % LoadpretrainednetworkButton
        function LoadpretrainednetworkButtonPushed(app, event)
            loadMyNetFun(app);
        end

        % Value changed function: FreezeinitiallayersButton
        function FreezeinitiallayersButtonValueChanged(app, event)
            value = app.FreezeinitiallayersButton.Value;
            
        end

        % Value changed function: FromlayerEditField
        function FromlayerEditFieldValueChanged(app, event)
            app.Freezeinitiallayers_from_layer = app.FromlayerEditField.Value;
        end

        % Value changed function: tolayerEditField
        function tolayerEditFieldValueChanged(app, event)
            app.Freezeinitiallayers_to_layer = app.tolayerEditField.Value;
        end

        % Callback function
        function ClassifyperframeButtonValueChanged(app, event)
            
        end

        % Value changed function: ReplacefinalLayerButton
        function ReplacefinalLayerButtonValueChanged(app, event)
            value = app.ReplacefinalLayerButton.Value;
            
        end

        % Value changed function: VideoDropDown_3
        function VideoDropDown_3ValueChanged(app, event)
            app.selectedvid = app.VideoDropDown_3.Value;
            app.vidpathtouse = app.vidpath_cmpltd;
            Loadvid(app, event);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if ~isempty(app.vidpath)   && ~isempty(app.selpath)  && ~isempty(app.NewProjectdirEditField.Value) &&  ~strcmpi(app.NewProjectdirEditField.Value,'N/A')
                app.StatusEditField.Value = 'Creating new dataset!';
                drawnow;
                %                 new_frame_hist = [app.PriorframestolookatEditField_2.Value,app.FutureframestolookatEditField_2.Value];
                dir_old = app.selpath;
                vid_dir = app.vidpath;
                Classes = dir(dir_old);
                Classes(find(contains({Classes.name},'.')==1)) = []; % remove files
                dir_new = app.NewProjectdirEditField.Value; % Create folders
                if exist(dir_new) == 0
                    mkdir(dir_new);
                end
                for i = 1:length(Classes)
                    if exist(fullfile(dir_new,Classes(i).name)) == 0
                        mkdir(fullfile(dir_new,Classes(i).name));
                    end
                end
                % Load frame info
                if exist(fullfile(dir_old,'LabelledFrameInfo.mat')) ~=0
                    load(fullfile(dir_old,'LabelledFrameInfo.mat'));
                end
                % % load vid info
                if exist(fullfile(vid_dir,'vid_info.mat'))
                    load(fullfile(vid_dir,'vid_info.mat'));
                end
                t = struct2table(all_frame_info);
                prev_frame_hist = [mean(t.Frame_history(:,1)) ,mean(t.Frame_history(:,2)) ];
                
                clear t
                % For each video, find labelled frames for the given video
                for iii = 1:length(vid_info)
                    vidname = [];
                    app.StatusEditField.Value = strjoin({num2str(iii),'/',num2str(length(vid_info))});
                    drawnow;
                    clear vid_temp
                    idx = find ( cellfun(@(asd) any(strcmpi(asd, vid_info(iii).Name )),{all_frame_info.Name}) == 1); % which frames labelled
                    if exist(fullfile(vid_dir,vid_info(iii).Name)) ~=0 % load vid
                        app.vidpathtouse = app.vidpath;
                        vidname = vid_info(iii).Name;
                        LoadVidFun(app,vidname);
                        %                         vid_temp = VideoReader(fullfile(vid_dir,vid_info(iii).Name));
                    elseif exist(fullfile(vid_dir,'Completed',vid_info(iii).Name)) ~=0
                        app.vidpathtouse = app.vidpath_cmpltd;
                        vidname = vid_info(iii).Name;
                        LoadVidFun(app,vidname);
                        %                         vid_temp = VideoReader(fullfile(vid_dir,'Completed',vid_info(iii).Name));
                    else
                        disp(strjoin({'Vid not found:',vid_info(iii).Name}));
                        drawnow;
                    end
                    if ~isempty(vidname)
                        for a = 1:length(idx)
                            iFrame = all_frame_info(idx(a)).iFrame;
                            rotation_k = all_frame_info(idx(a)).Rotation;
                            % Read appropriate frames
                            % Check if frame history is used for volume input to CNN
                            if ~isempty(app.frame_mat)
                                % disp('Frame read from memory')
                                frame = imrotate(app.frame_mat(:,:, iFrame),app.rotation_k);
                            else
                                frame = imrotate(read(app.vid, iFrame),app.rotation_k);
                            end
                            %First frame
                            if app.FirststackaverageframesfromEditField.Value~=0
                                framemat = []; % Generate history image
                                if app.FirststackaverageframesfromEditField.Value <0 && iFrame+app.FirststackaverageframesfromEditField.Value <1
                                    first_idx = 1;
                                elseif  app.FirststackaverageframesfromEditField.Value >0 && iFrame+app.FirststackaverageframesfromEditField.Value > app.NumFrames
                                    first_idx = app.NumFrames;
                                else
                                    first_idx = iFrame+app.FirststackaverageframesfromEditField.Value;
                                end
                                if app.toEditField_2.Value <0 && iFrame+app.toEditField_2.Value <1
                                    second_idx = 1;
                                elseif  app.toEditField_2.Value >0 && iFrame+app.toEditField_2.Value > app.NumFrames
                                    second_idx = app.NumFrames;
                                else
                                    second_idx = iFrame+app.toEditField_2.Value;
                                end
                                frame_list_stack = [first_idx:second_idx];
                                for iiFrame = frame_list_stack
                                    clear frametemp
                                    iiFrame
                                    if ~isempty(app.frame_mat)
                                        % disp('Frame read from memory')
                                        frametemp = app.frame_mat(:,:, iiFrame);
                                    else
                                        frametemp = read(app.vid, iiFrame);
                                    end
                                    if isempty(framemat)
                                        framemat = frametemp;
                                    else
                                        framemat = cat(4,framemat,frametemp);
                                    end
                                end
                                if isempty(framemat)
                                    frame_pre = frame;
                                else
                                    if length(size(framemat))==4
                                        framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                                    end
                                    frame_pre = imrotate(framemat,app.rotation_k);
                                end
                            else
                                frame_pre = frame; % past is the same as current
                            end
                            %Third frame
                            if app.ThirdstackaverageframesfromEditField.Value~=0
                                framemat = []; % Generate history image
                                if app.ThirdstackaverageframesfromEditField.Value <0 && iFrame+app.ThirdstackaverageframesfromEditField.Value <1
                                    first_idx = 1;
                                elseif  app.ThirdstackaverageframesfromEditField.Value >0 && iFrame+app.ThirdstackaverageframesfromEditField.Value > app.NumFrames
                                    first_idx = app.NumFrames;
                                else
                                    first_idx = iFrame+app.ThirdstackaverageframesfromEditField.Value;
                                end
                                if app.toEditField_4.Value <0 && iFrame+app.toEditField_4.Value <1
                                    second_idx = 1;
                                elseif  app.toEditField_4.Value >0 && iFrame+app.toEditField_4.Value > app.NumFrames
                                    second_idx = app.NumFrames;
                                else
                                    second_idx = iFrame+app.toEditField_4.Value;
                                end
                                frame_list_stack = [first_idx:second_idx];
                                for iiFrame = frame_list_stack
                                    clear frametemp
                                    if ~isempty(app.frame_mat)
                                        % disp('Frame read from memory')
                                        frametemp = app.frame_mat(:,:, iiFrame);
                                    else
                                        frametemp = read(app.vid, iiFrame);
                                    end
                                    if isempty(framemat)
                                        framemat = frametemp;
                                    else
                                        framemat = cat(4,framemat,frametemp);
                                    end
                                end
                                if isempty(framemat)
                                    frame_post = frame;
                                else
                                    if length(size(framemat))==4
                                        framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                                    end
                                    frame_post = imrotate(framemat,app.rotation_k);
                                end
                            else
                                frame_post = frame; % past is the same as current
                            end
                            % middle frame
                            if app.SecondstackaverageframesfromEditField.Value~=0
                                frame_original = frame;
                                framemat = []; % Generate history image
                                if app.SecondstackaverageframesfromEditField.Value <0 && iFrame+app.SecondstackaverageframesfromEditField.Value <1
                                    first_idx = 1;
                                elseif  app.SecondstackaverageframesfromEditField.Value >0 && iFrame+app.SecondstackaverageframesfromEditField.Value > app.NumFrames
                                    first_idx = app.NumFrames;
                                else
                                    first_idx = iFrame+app.SecondstackaverageframesfromEditField.Value;
                                end
                                if app.toEditField_3.Value <0 && iFrame+app.toEditField_3.Value <1
                                    second_idx = 1;
                                elseif  app.toEditField_3.Value >0 && iFrame+app.toEditField_3.Value > app.NumFrames
                                    second_idx = app.NumFrames;
                                else
                                    second_idx = iFrame+app.toEditField_3.Value;
                                end
                                frame_list_stack = [first_idx:second_idx];
                                for iiFrame = frame_list_stack
                                    clear frametemp
                                    if ~isempty(app.frame_mat)
                                        % disp('Frame read from memory')
                                        frametemp = app.frame_mat(:,:, iiFrame);
                                    else
                                        frametemp = read(app.vid, iiFrame);
                                    end
                                    if isempty(framemat)
                                        framemat = frametemp;
                                    else
                                        framemat = cat(4,framemat,frametemp);
                                    end
                                end
                                if isempty(framemat)
                                    frame = frame_original;
                                else
                                    if length(size(framemat))==4
                                        framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                                    end
                                    frame = imrotate(framemat,app.rotation_k);
                                end
                            else
                                %                 frame = frame_original; %  is the same as current
                            end
                            
                            
                            % Make sure they are greyscale and put back into 3D
                            %             frame = imrotate(read(app.vid, iFrame),app.rotation_k);
                            frame_t1 = squeeze(mean(frame,3,'omitnan')); % Current
                            frame = uint8(repmat(frame_t1,[1 1 3]));
                            frame_t2 = squeeze(mean(frame_pre,3,'omitnan')); % Pre
                            frame_pre = uint8(repmat(frame_t2,[1 1 3]));
                            frame_t3 = squeeze(mean(frame_post,3,'omitnan')); % Future
                            frame_post(:,:,1:3) = uint8(repmat(frame_t3,[1 1 3]));
                            temp = frame_t1-frame_t2;
                            frame_curpre(:,:,1:3) = uint8(repmat(temp,[1 1 3]));
                            temp = frame_t1-frame_t3;
                            frame_curpost(:,:,1:3) = uint8(repmat(temp,[1 1 3]));
                            RGB_frame = uint8(cat(3,frame_t2,frame_t1,frame_t3));
                            %%%% end of Frame creation for CNN
                            
                            
                            % Add frame to params to be later saved if needed
                            app.RGB_frame = RGB_frame;
                            
                            %
                            %                             if ~isempty(app.frame_mat)
                            %                                 % disp('Frame read from memory')
                            %                                 frame = imrotate(app.frame_mat(:,:,iFrame),app.rotation_k);
                            %                             else
                            %                                 frame = imrotate(read(vid_temp, iFrame),app.rotation_k);
                            %                             end
                            %                             if new_frame_hist(1)>0
                            %                                 clear framemat % Generate history image
                            %                                 for i = 1:new_frame_hist(1)
                            %                                     clear frametemp
                            %                                     if iFrame-i>0
                            %                                         iiFrame = iFrame-i;
                            %                                         if ~isempty(app.frame_mat)
                            %                                             % disp('Frame read from memory')
                            %                                             frametemp = app.frame_mat(:,:,iiFrame);
                            %                                         else
                            %                                             frametemp = read(vid_temp, iiFrame);
                            %                                         end
                            %                                     else
                            %                                         frametemp = nan(size(frame));
                            %                                     end
                            %                                     if ~(all(all(all(isnan(frametemp)))))
                            %                                         if i == 1
                            %                                             framemat = frametemp;
                            %                                         else
                            %                                             framemat = cat(4,framemat,frametemp);
                            %                                         end
                            %                                     end
                            %                                 end
                            %                                 if exist('framemat','var')==0
                            %                                     frame_pre = frame;
                            %                                 else
                            %                                     if length(size(framemat))==4
                            %                                         framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                            %                                     end
                            %                                     frame_pre = imrotate(framemat,app.rotation_k);
                            %                                 end
                            %
                            %                             else
                            %                                 frame_pre = frame; % past is the same as current
                            %                             end
                            %                             if new_frame_hist(2)>0
                            %                                 clear framemat % Generate future image
                            %                                 for i = 1:new_frame_hist(2)
                            %                                     if iFrame+i<vid_temp.NumFrames
                            %                                         iiFrame = iFrame+i;
                            %                                         if ~isempty(app.frame_mat)
                            %                                             % disp('Frame read from memory')
                            %                                             frametemp = app.frame_mat(:,:,iiFrame);
                            %                                         else
                            %                                             frametemp = read(vid_temp, iiFrame);
                            %                                         end
                            %                                     else
                            %                                         frametemp = nan(size(frame));
                            %                                     end
                            %                                     if ~(all(all(all(isnan(frametemp)))))
                            %                                         if i == 1
                            %                                             framemat = frametemp;
                            %                                         else
                            %                                             framemat = cat(4,framemat,frametemp);
                            %                                         end
                            %                                     end
                            %                                 end
                            %                                 if exist('framemat','var')==0
                            %                                     frame_post = frame;
                            %                                 else
                            %                                     if length(size(framemat))==4
                            %                                         framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                            %                                     end
                            %                                     frame_post = imrotate(framemat,app.rotation_k);
                            %                                 end
                            %
                            %                             else
                            %                                 frame_post = frame; % future is the same as current
                            %                             end
                            %
                            %                             % Make sure they are greyscale and put back into 3D
                            %                             %             frame = imrotate(read(vid, iFrame),app.rotation_k);
                            %                             frame_t1 = squeeze(mean(frame,3,'omitnan')); % Current
                            %                             frame_t2 = squeeze(mean(frame_pre,3,'omitnan')); % Pre
                            %                             frame_t3 = squeeze(mean(frame_post,3,'omitnan')); % Future
                            %                             RGB_frame = uint8(cat(3,frame_t2,frame_t1,frame_t3));


                            %             figure; imshow(RGB_frame);
                            %%%% end of Frame creation for CNN
                            pic_name = char(strcat(dir_new,'\',all_frame_info(idx(a)).Label,'\',strrep(all_frame_info(idx(a)).Name,app.CurrentViddirEditField_2.Value,''),num2str(all_frame_info(idx(a)).iFrame,'%07d'),'.png'));
                            imwrite(RGB_frame,pic_name);
                        end
                    end
                end
                for i = 1:length(all_frame_info)
                    all_frame_info(i).Frame_history = new_frame_hist;
                end
                
                filenametemp = fullfile(dir_new,'\LabelledFrameInfo.mat');
                save(filenametemp,'all_frame_info','-mat');
                
            else
                app.StatusEditField.Value = 'Please select project, vid and new project dirs!';
            end
            app.StatusEditField.Value = 'Done';
            drawnow;
        end

        % Button pushed function: SelectProjectdirButton_3
        function SelectProjectdirButton_3Pushed(app, event)
            app.StatusEditField.Value = 'Selecting dir';
            selpath = uigetdir;
            app.NewProjectdirEditField.Value = selpath;
            drawnow;
            figure(app.ClassificationApp);
        end

        % Button pushed function: LoadpretrainednetButton_3
        function LoadpretrainednetButton_3Pushed(app, event)
            loadMyNetFun(app);
        end

        % Button pushed function: AnalysenetworkButton
        function AnalysenetworkButtonPushed(app, event)
            if ~isempty(app.net)
                analyzeNetwork(app.net);
            end
        end

        % Value changed function: LabelDropDown_2
        function LabelDropDown_2ValueChanged(app, event)
            value = app.LabelDropDown_2.Value;
            app.LabelDropDown.Value = value;
            app.cur_frame_info.Label = value;
            app.ChooseaclassDropDown.Value = value;
            if isfield(app.vid,'Name')
                app.cur_frame_info.Name = app.vid.Name;
            end
            if isfield(app.vid,'iFrame')
                app.cur_frame_info.iFrame = app.iFrame;
            end
            if isfield(app.vid,'rotation_k')
                app.cur_frame_info.Rotation = app.rotation_k;
            end
            app.cur_frame_info.Frame_history = [app.FirststackaverageframesfromEditField.Value,app.ThirdstackaverageframesfromEditField.Value];
            disp(value);
            app.UseEditField.Value = value;
        end

        % Button pushed function: DreamButton
        function DreamButtonPushed(app, event)
            k = find(strcmpi({app.class_dirs.Label{:}}, app.LabelDropDown_2.Value)==1);
            class_to_dream =  k ;
            iterations = app.NumberofiterationsEditField.Value;
            layerName = app.net.Layers(app.SelectlastfullyconnectedlayerSuggestedEditField.Value).Name;
            app.StatusEditField.Value = char(strjoin({'Dreaming of',char(app.net.Layers(end).Classes(class_to_dream))}));
            drawnow;
            I = deepDreamImage(app.net,layerName,class_to_dream, ...
                'PyramidLevels',1, ...
                'Verbose',false, ...
                'NumIterations',iterations,'ExecutionEnvironment','auto');
            imshow(I,'Parent',app.MainPic_3); title(app.MainPic_3,strjoin({app.LabelDropDown_2.Value,'-',num2str(iterations),'-',strrep(app.mynet_name,'_',' ')}));
            app.StatusEditField.Value = 'Done';
            drawnow;
            app.I = I;
        end

        % Button pushed function: 
        % OptionalselectdirsavingthedreamButton
        function OptionalselectdirsavingthedreamButtonPushed(app, event)
            app.dream_pic_dir = uigetdir;
            drawnow;
            figure(app.ClassificationApp);
        end

        % Button pushed function: SaveDreamButton
        function SaveDreamButtonPushed(app, event)
            FigA = figure;
            
            imshow(app.I); title(strjoin({app.LabelDropDown_2.Value,'-',num2str(app.NumberofiterationsEditField.Value),'-',app.mynet_name}));
            set(gca,'xtick',[]);
            set(gca,'xticklabel',[]);
            set(gca,'ytick',[]);
            set(gca,'yticklabel',[]);
            
            saveas(FigA,fullfile(app.dream_pic_dir,strcat(app.LabelDropDown_2.Value,'-',num2str(app.NumberofiterationsEditField.Value),'-',strrep(app.mynet_name,'_',''))));
        end

        % Button pushed function: 
        % ClassifyvidswithspecifiedframesButton
        function ClassifyvidswithspecifiedframesButtonPushed(app, event)
            [file,path] = uigetfile(app.selpath);
            t = load(fullfile(path,file));
            tt = fieldnames(t);
            app.select_frames = t.(tt{1});
            
            
            if isempty(app.select_frames)
                app.StatusEditField.Value = strcat('No specified frames found in vid dir, select_frames.mat is required');
                drawnow;
            else
                for i = 1:length(app.select_frames)
                    run_class = 0;
                    vidname = char(app.select_frames(i).Name);
                    %                     disp(fullfile(app.vidpath,vidname));
                    if app.reclassify_vids_constant ~=1 && exist(fullfile(app.vidpath,vidname)) ~=0
                        app.vidpathtouse = app.vidpath;
                        run_class = 1;
                    elseif app.reclassify_vids_constant == 1 && exist(fullfile(app.vidpath,vidname)) ~=0
                        app.vidpathtouse = app.vidpath;
                        run_class = 1;
                    elseif app.reclassify_vids_constant == 1 && exist(fullfile(app.vidpath_cmpltd,vidname)) ~=0
                        app.vidpathtouse = app.vidpath_cmpltd;
                        run_class = 1;
                    end
                    
                    if run_class == 0
                        app.StatusEditField.Value = strjoin({'Video already done or not found, ',num2str(i),'/',num2str(length(app.select_frames)),vidname});
                        drawnow;
                    elseif run_class == 1
                        app.StatusEditField.Value = strjoin({'Reading video',num2str(i),'/',num2str(length(app.select_frames)),vidname});
                        %Loadvid
                        LoadVidFun(app,vidname);
%                         loadClassifiedDataIfAvail(app);
                        % Classify
                        if app.SkipvideosifanerroroccursduringclassificationCheckBox.Value == 1
                            try
                                Classify_vid_fun(app);
                            end
                        else
                            Classify_vid_fun(app);
                        end
                        %Move completed video
                        
                        if app.MovecompletedvidsButton.Value == 1
                            myfolder = strcat(app.vidpath,'\Completed'); %where completed videos will be moved to
                            if exist(myfolder,"dir") == 0
                                mkdir(myfolder);
                            end
                            %                             disp(app.vidpath);disp(app.vid.Name); disp(fullfile(myfolder,char(app.vid.Name)));
                            movefile(fullfile(app.vidpath,char(app.vid.Name)),fullfile(myfolder,char(app.vid.Name)));
                            update_vid_dropdown_fun(app);
                        end
                    end
                end
            end
        end

        % Button pushed function: NextvidButton
        function NextvidButtonPushed(app, event)
            vids_already_labelled = unique({app.all_frame_info.Name});
            %             vids= app.videos_avail;
            %             all_vids = {vids.name};
            all_vids = app.videos_avail;
            non_labelledvids = {};
            for i = 1:length(all_vids)
                if all(strcmpi(vids_already_labelled,all_vids{i}) == 0)
                    non_labelledvids{end+1} = all_vids{i};
                end
            end
            if ~isempty(non_labelledvids)
                app.selectedvid = non_labelledvids{1};
                app.VideoDropDown_2.Value = non_labelledvids{1};
                app.VideoDropDown.Value = non_labelledvids{1};
                app.vidpathtouse = app.vidpath;
                app.StatusEditField.Value = strjoin({'Next vid = ' ,non_labelledvids{1} ,num2str(length(vids_already_labelled)),'/',num2str(length(all_vids))});
                
            else
                app.StatusEditField.Value = strjoin({'All videos in the dir have labels'});
            end
            drawnow;
            
        end

        % Button pushed function: AddmissingpicstothefoldersButton
        function AddmissingpicstothefoldersButtonPushed(app, event)
            if ~isempty(app.vidpath)   && ~isempty(app.selpath)  && ~isempty(app.NewProjectdirEditField.Value) &&  ~strcmpi(app.NewProjectdirEditField.Value,'N/A')
                
                dir_old = app.selpath;
                vid_dir = app.vidpath;
                dir_new = app.NewProjectdirEditField.Value; % Create folders
                all_frame_info = app.all_frame_info;
                Classes = dir(dir_new);
                Classes(find(contains({Classes.name},'.')==1)) = []; % remove files
                
                new_frame_hist = [app.PriorframestolookatEditField_2.Value,app.FutureframestolookatEditField_2.Value];
                
                
                if exist(dir_new) == 0
                    mkdir(dir_new);
                end
                for i = 1:length(Classes)
                    if exist(fullfile(dir_new,Classes(i).name)) == 0
                        mkdir(fullfile(dir_new,Classes(i).name));
                    end
                end
                Classes = {Classes.name};
                for i = 1:length(all_frame_info)
                    frame_fullname = fullfile(dir_new,all_frame_info(i).Label,strcat( strrep(all_frame_info(i).Name,app.CurrentViddirEditField_2.Value,''), num2str(all_frame_info(i).iFrame,'%07d'),'.png'));
                    if exist(frame_fullname) ~= 0 % if the frame does not exist, add it
                        disp(strjoin({'framefound ',num2str(i)}));
                    else
                        app.StatusEditField.Value = strjoin({'Frame not found, adding:',strcat( strrep(all_frame_info(i).Name,app.CurrentViddirEditField_2.Value,''), num2str(all_frame_info(i).iFrame,'%07d'),'.png')});
                        drawnow;
                        if exist('vid_temp') == 0 || ~strcmpi(vid_temp.Name,all_frame_info(i).Name) % if the video open does not match, open new one
                            if exist(fullfile(vid_dir,all_frame_info(i).Name)) ~=0 % load vid
                                vid_temp = VideoReader(fullfile(vid_dir,all_frame_info(i).Name));
                            elseif exist(fullfile(vid_dir,'Completed',all_frame_info(i).Name)) ~=0
                                vid_temp = VideoReader(fullfile(vid_dir,'Completed',all_frame_info(i).Name));
                            else
                                disp(strjoin({'Vid not found:',vid_info(iii).Name}));
                                drawnow;
                            end
                        end
                        iFrame = all_frame_info(i).iFrame;
                        rotation_k = all_frame_info(i).Rotation;
                        
                        if ~isempty(app.frame_mat)
                            % disp('Frame read from memory')
                            frame = imrotate(app.frame_mat(:,:,iFrame),app.rotation_k);
                        else
                            frame = imrotate(read(vid_temp, iFrame),app.rotation_k);
                        end
                        if new_frame_hist(1)>0
                            clear framemat % Generate history image
                            for i = 1:new_frame_hist(1)
                                clear frametemp
                                if iFrame-i>0
                                    iiFrame = iFrame-i;
                                    if ~isempty(app.frame_mat)
                                        % disp('Frame read from memory')
                                        frametemp = app.frame_mat(:,:,iiFrame);
                                    else
                                        frametemp = read(vid_temp, iiFrame);
                                    end
                                else
                                    frametemp = nan(size(frame));
                                end
                                if ~(all(all(all(isnan(frametemp)))))
                                    if i == 1
                                        framemat = frametemp;
                                    else
                                        framemat = cat(4,framemat,frametemp);
                                    end
                                end
                            end
                            if exist('framemat','var')==0
                                frame_pre = frame;
                            else
                                if length(size(framemat))==4
                                    framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                                end
                                frame_pre = imrotate(framemat,app.rotation_k);
                            end
                            
                        else
                            frame_pre = frame; % past is the same as current
                        end
                        if new_frame_hist(2)>0
                            clear framemat % Generate future image
                            for i = 1:new_frame_hist(2)
                                if iFrame+i<vid_temp.NumFrames
                                    iiFrame = iFrame+i;
                                    if ~isempty(app.frame_mat)
                                        % disp('Frame read from memory')
                                        frametemp = app.frame_mat(:,:,iiFrame);
                                    else
                                        frametemp = read(vid_temp, iiFrame);
                                    end
                                else
                                    frametemp = nan(size(frame));
                                end
                                if ~(all(all(all(isnan(frametemp)))))
                                    if i == 1
                                        framemat = frametemp;
                                    else
                                        framemat = cat(4,framemat,frametemp);
                                    end
                                end
                            end
                            if exist('framemat','var')==0
                                frame_post = frame;
                            else
                                if length(size(framemat))==4
                                    framemat= uint8(squeeze(mean(framemat,4,'omitnan')));
                                end
                                frame_post = imrotate(framemat,app.rotation_k);
                            end
                            
                        else
                            frame_post = frame; % future is the same as current
                        end
                        
                        % Make sure they are greyscale and put back into 3D
                        %             frame = imrotate(read(vid, iFrame),app.rotation_k);
                        frame_t1 = squeeze(mean(frame,3,'omitnan')); % Current
                        frame_t2 = squeeze(mean(frame_pre,3,'omitnan')); % Pre
                        frame_t3 = squeeze(mean(frame_post,3,'omitnan')); % Future
                        RGB_frame = uint8(cat(3,frame_t2,frame_t1,frame_t3));
                        %             figure; imshow(RGB_frame);
                        %%%% end of Frame creation for CNN
                        pic_name = char(strcat(dir_new,'\',all_frame_info(i).Label,'\',strrep(all_frame_info(i).Name,app.CurrentViddirEditField_2.Value,''),num2str(all_frame_info(i).iFrame,'%07d'),'.png'));
                        imwrite(RGB_frame,pic_name);
                        
                        
                    end
                end
                filenametemp = fullfile(dir_new,'\LabelledFrameInfo.mat');
                save(filenametemp,'all_frame_info','-mat');
            else
                app.StatusEditField.Value = 'Please select project, vid and new project dirs!';
            end
            app.StatusEditField.Value = 'Done';
            drawnow;
        end

        % Button pushed function: 
        % EditframefilebasedonfolderspngimagesButton
        function EditframefilebasedonfolderspngimagesButtonPushed(app, event)
            if ~isempty(app.vidpath)   && ~isempty(app.selpath)  %&& ~isempty(app.NewProjectdirEditField.Value) &&  ~strcmpi(app.NewProjectdirEditField.Value,'N/A')
                all_frame_info = app.all_frame_info;
                dir_old = app.selpath;
                vid_dir = app.vidpath;
                %                 dir_new = app.NewProjectdirEditField.Value; % Create folders
                Classes = dir(dir_old);
                Classes(find(contains({Classes.name},'.')==1)) = []; % remove files
                Classes_new = {Classes.name};
                app.StatusEditField.Value = 'Starting frame check!';
                drawnow;
                display('framecheck started');
                if ~isempty(all_frame_info)
                    all_frame_info_new = all_frame_info([]);
                end
                num_keep = 0;
                for i = 1:length(Classes_new)
                    frames_in_class = dir(fullfile(dir_old, Classes_new{i},'*.png'));
                    for ii = 1:length({frames_in_class.name})
                        frame_vidname = strrep(frames_in_class(ii).name,'.png','');
                        frame_iFrame = frame_vidname(end-6:end);
                        frame_vidname = strrep(frame_vidname,frame_iFrame,app.CurrentViddirEditField_2.Value);
                        frame_iFrame = str2num(frame_iFrame);
                        idx = find(contains({all_frame_info.Name},frame_vidname) == 1 & [all_frame_info.iFrame] == frame_iFrame);
                        
                        if ~isempty(idx)
                            for idx_i = 1:length(idx)
                                idxx = idx(idx_i);
                                if ~strcmpi(all_frame_info(idxx).Label,Classes_new{i})
                                    disp(strjoin({frames_in_class(ii).name, 'Frame class changed from',all_frame_info(idxx).Label,'to',Classes_new{i}}));
                                    all_frame_info(idxx).Label = Classes_new{i};
                                    num_keep = num_keep + 1;
                                end
                            end
                            if length(idx)>1
                                disp(strjoin({'Multiple instances of the frame:',frame_vidname,num2str(frame_iFrame),'were identified'}));
                                idx (2:end) = [];
                                all_frame_info(idx) = [];
                            end
                            idx = find(strcmpi({all_frame_info.Name},frame_vidname) == 1 & [all_frame_info.iFrame] == frame_iFrame);
                            all_frame_info_new(end+1) = all_frame_info(idx);
                        else
                            disp(strjoin({'Error, frame not found = ',frames_in_class(ii).name}));
                        end
                    end
                end
                filenametemp = fullfile(dir_old,strcat('\LabelledFrameInfo_old',datestr(now,'dd-mm-yyyy'),'.mat'));
                save(filenametemp,'all_frame_info','-mat');
                all_frame_info = all_frame_info_new;
                filenametemp = fullfile(dir_old,'\LabelledFrameInfo.mat');
                save(filenametemp,'all_frame_info','-mat');
                app.all_frame_info = all_frame_info;
                
            else
                app.StatusEditField.Value = 'Please select project, vid and new project dirs!';
            end
            disp(strjoin({'Done, changed #frames = ',num2str(num_keep)}));
            app.StatusEditField.Value = strjoin({'Done, changed #frames = ',num2str(num_keep)});
            drawnow;
        end

        % Button pushed function: ResultstableButton
        function ResultstableButtonPushed(app, event)
            NetvsHumanResTable(app);
        end

        % Button pushed function: NextFrame_HvN
        function NextFrame_HvNButtonPushed(app, event)
            if isempty(app.Rand_frame_dataset)
                app.StatusEditField.Value = 'Generate a Random frame dataset!';
            elseif ~isempty(app.Rand_frame_dataset) && app.test_frame_idx < length(app.Rand_frame_dataset.frame)
                % Confirm selected label
                app.Results_human.YPred{app.test_frame_idx} = app.LabelsListBox.Value;
                % Load next frame
                app.test_frame_idx = app.test_frame_idx + 1;
                app.StatusEditField.Value = strjoin({'Frame =',num2str(app.test_frame_idx),'/',num2str(length(app.Rand_frame_dataset.frame))});
                imshow(imread(app.Rand_frame_dataset.frame{app.test_frame_idx}),'Parent',app.MainPic_4);
                drawnow;
            elseif ~isempty(app.Rand_frame_dataset) && app.test_frame_idx == length(app.Rand_frame_dataset.frame)
                % Confirm selected label
                app.Results_human.YPred{app.test_frame_idx} = app.LabelsListBox.Value;
                % Save results when test is completed
                app.StatusEditField.Value = strjoin({'Done. Took = ',datestr(now-app.test_starttime, 'mm:ss' ),'min:ss'});
                filenametemp = fullfile(app.selpath,strcat('Results_human_',app.IDEditField.Value,'_',datestr(now, 'dd_MM_yyyy_HH_mm' ),'.mat'));
                Results_human = app.Results_human;
                save(filenametemp,'Results_human','-mat');
                NetvsHumanResTable(app);
            end
        end

        % Button pushed function: Start_HvN
        function Start_HvNButtonPushed(app, event)
            % Load and display a frame from the app.Rand_frame_dataset
            if isempty(app.Rand_frame_dataset)
                app.StatusEditField.Value = 'Generate a Random frame dataset!';
            else
                app.Results_human = app.Rand_frame_dataset;
                app.StatusEditField.Value = 'Testing Human accuracy';
                drawnow;
                app.test_starttime = now();
                app.test_frame_idx = 1;
                imshow(imread(app.Rand_frame_dataset.frame{1}),'Parent',app.MainPic_4);
                drawnow;
            end
        end

        % Button pushed function: GeneratearandomlistofframesButton
        function GeneratearandomlistofframesButtonPushed(app, event)
            % Loop for the specified number of frames
            for i = 1:app.offramestotestEditField.Value
                % Randomly select a class
                class_selected = ceil(rand*(length(app.class_dirs.Label)));
                selected_dir = fullfile(app.selpath,app.class_dirs.Label{class_selected});
                % Randomly select a frame for the dir
                frames_in_dir = dir(fullfile(selected_dir,'*.png'));
                frame_selected = ceil(rand*(length(frames_in_dir)));
                Rand_frame_dataset.frame{i} = fullfile(selected_dir,frames_in_dir(frame_selected).name);
                Rand_frame_dataset.RealLabel{i} = app.class_dirs.Label{class_selected};
            end
            % Save Results
            app.Rand_frame_dataset = Rand_frame_dataset;
            app.StatusEditField.Value = 'Dateset generated';
            drawnow;
            
        end

        % Button pushed function: TesttheNetButton
        function TesttheNetButtonPushed(app, event)
            % Check if net is loaded
            net = app.net;
            if ~isempty(net)
                % Test Rand dataset
                if isempty(app.Rand_frame_dataset)
                    app.StatusEditField.Value = 'Generate a Random frame dataset!';
                else
                    Results = app.Rand_frame_dataset;
                    app.StatusEditField.Value = 'Testing Net accuracy';
                    drawnow;
                    for i = 1:length(app.Rand_frame_dataset.frame)
                        RGB_frame = imread(app.Rand_frame_dataset.frame{i});
                        frame = imresize(RGB_frame, app.framefinalsize);
                        [YPred,probs] = classify(net,frame);
                        probs = probs*100;
                        Results.YPred{i} = char(YPred);
                        Results.probs{i} = probs;
                    end
                    timetemp = toc;
                    app.StatusEditField.Value = strjoin({'Done. Took = ',num2str(timetemp),'s'});
                    % Save Results
                    filenametemp = fullfile(app.selpath,strcat('Results_net_',datestr(now, 'dd_MM_yyyy_HH_mm' ),'.mat'));
                    save(filenametemp,'Results','-mat');
                end
            else
                app.StatusEditField.Value = 'Load Trained net!';
            end
            drawnow;
        end

        % Value changed function: LabelsListBox
        function LabelsListBoxValueChanged(app, event)
            app.cur_frame_info.Label = app.LabelsListBox.Value;
            disp(app.LabelsListBox.Value);
        end

        % Value changed function: ClassifyperframeButton
        function ClassifyperframeButtonValueChanged2(app, event)
            ClassifySingleFrameFun(app, event)
        end

        % Value changed function: DefaultframerotationEditField
        function DefaultframerotationEditFieldValueChanged(app, event)
            app.rotation_k = app.DefaultframerotationEditField.Value;
            app.RotateEditField.Value = app.rotation_k;
            app.RotateEditField_2.Value = app.rotation_k;
        end

        % Button pushed function: FindexamplesButton
        function FindexamplesButtonPushed(app, event)
            fldnms = fieldnames(app.Classified_datastruct.probs);
            num_of_examples = nan(length(fldnms),1);
            idxes = {}; items_t = {};
            class_idx = find(strcmpi(app.class_dirs.Label,app.LabelDropDown.Value) ==1);
            for fldnm_i  = 1:length(fldnms)
                idxes{fldnm_i} = find(app.Classified_datastruct.probs.(fldnms{fldnm_i})(:,class_idx) >= app.CertaintythresholdEditField.Value);
                num_of_examples(fldnm_i) = length(idxes{fldnm_i});
                if num_of_examples(fldnm_i) >0
                    items_t{end+1} = app.Classified_datastruct.names.(fldnms{fldnm_i});
                end
            end
            app.FromvidsEditField.Value = sum(num_of_examples>0);
            app.ExampleframesfoundEditField.Value = sum(num_of_examples);
            % populate video dropdown list
            if  app.FromvidsEditField.Value>0
                app.VideoDropDown_4.Items = items_t;
                app.VideoDropDown_4.Value = items_t{1};
            end
        end

        % Value changed function: ChooseaclassDropDown
        function ChooseaclassDropDownValueChanged(app, event)
            value = app.ChooseaclassDropDown.Value;
            app.LabelDropDown_2.Value = value;
            app.LabelDropDown.Value = value;
            app.cur_frame_info.Label = value;
        end

        % Button pushed function: 
        % ChecksettingsoutputinMatlabCommandWindowButton
        function ChecksettingsoutputinMatlabCommandWindowButtonPushed(app, event)
            assess_net_training_settings_fun(app);
        end

        % Button pushed function: AssesstrainednetButton
        function AssesstrainednetButtonPushed(app, event)
            assess_net_training_settings_fun(app);
            assess_trained_net(app)
        end

        % Button pushed function: LoadvidButton_3
        function LoadvidButton_3Pushed(app, event)
            if app.FromvidsEditField.Value == 0
                disp('No examples found');
            else
                t = app.VideoDropDown_4.Value;
                idx = ismember(t,'-');

                ii=strfind([ 0 idx],[0 1 1 1]);
                vidname = strcat(t(1:ii-1),app.CurrentViddirEditField_2.Value);
                % find vid
                if exist(fullfile(app.vidpath_cmpltd,vidname)) ~= 0
                    app.vidpathtouse = app.vidpath_cmpltd;
                    LoadVidFun(app,vidname);
                    app.ExampleSpinner.Value = 1; ExampleSpinnerValueChanged(app, event);
                    % Update frame info if available
                    loadClassifiedDataIfAvail(app);
                    % Check if vid.info exists for rotation
                    LoadvidInfo(app);
                elseif exist(fullfile(app.vidpath,vidname)) ~= 0
                    app.vidpathtouse = app.vidpath;
                    LoadVidFun(app,vidname);
                    app.ExampleSpinner.Value = 1; ExampleSpinnerValueChanged(app, event);
                    % Update frame info if available
                    loadClassifiedDataIfAvail(app);
                    % Check if vid.info exists for rotation
                    LoadvidInfo(app);
                else
                    disp(strjoin({'Could not find vid',fullfile(app.vidpath_cmpltd,vidname)}))
                end
            end

        end

        % Value changed function: FrameSpinner_3
        function FrameSpinner_3ValueChanged(app, event)
            if app.vid_load_status == 1
                app.StatusEditField.Value = 'Changing frame';
                app.iFrame =  app.FrameSpinner_3.Value;
                [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
            else
                app.iFrame = 1;
            end
        end

        % Value changed function: ExampleSpinner
        function ExampleSpinnerValueChanged(app, event)
            if  app.FromvidsEditField.Value>0
                if app.vid_load_status == 1

                    fldnms = fieldnames(app.Classified_datastruct.probs);
                    class_idx = find(strcmpi(app.class_dirs.Label,app.LabelDropDown.Value) ==1);
                    for fldnm_i  = 1:length(fldnms)
                        idxes{fldnm_i} = find(app.Classified_datastruct.probs.(fldnms{fldnm_i})(:,class_idx) >= app.CertaintythresholdEditField.Value);
                        num_of_examples(fldnm_i) = length(idxes{fldnm_i});
                        if num_of_examples(fldnm_i) >0
                            items_t{fldnm_i} = app.Classified_datastruct.names.(fldnms{fldnm_i});
                        end
                    end


                    fld_slct = find(strcmpi(items_t,app.VideoDropDown_4.Value)==1);
                    xmpls_in_this_vid = idxes{fld_slct};
                    slct_exmpl = app.ExampleSpinner.Value;
                    if length(xmpls_in_this_vid)<slct_exmpl
                        slct_exmpl = length(xmpls_in_this_vid);
                    elseif slct_exmpl<1
                        slct_exmpl = 1;
                    end
                    app.ExamplesinthisvidEditField.Value = length(xmpls_in_this_vid);
                    app.ExampleSpinner.Value = slct_exmpl;

                    app.iFrame =  xmpls_in_this_vid(slct_exmpl);
                    [frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame] = updateimage_mine(app);
                    display_RGB_frame(app,frame_pre,frame,frame_post,frame_curpre,frame_curpost,RGB_frame);
                else
                    disp('video not loaded');
                end
            else
                disp('No examples found');
            end
        end

        % Button pushed function: Makeselect_framesButton
        function Makeselect_framesButtonPushed(app, event)
            % Check how many frames are available per video 
            % Select every nth frame
            % Add into the struct file
            % Split struct files into batches
            % Save batches
            for vid_i = 1:length(app.videos_avail)
                select_frames_master(vid_i).Name = app.videos_avail{vid_i};
                obj_name = fullfile(app.vidpath,app.videos_avail{vid_i});
                vid_t = VideoReader(obj_name);
                select_frames_master(vid_i).frames_to_classify = [1:app.EverynthframeEditField.Value:vid_t.NumFrames];
            end
            % split into batches
            if app.BatchnumEditField.Value> length(select_frames_master)
                app.BatchnumEditField.Value = length(select_frames_master);
            end
            batch_idx = round(linspace(1,app.BatchnumEditField.Value,length(select_frames_master)));

            for batch_i = 1:length(unique(batch_idx))
                select_frames = select_frames_master(batch_idx == batch_idx(batch_i));
                save(strcat(app.selpath,'\select_frames_',num2str(batch_i)),'select_frames');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClassificationApp = uifigure('Visible', 'off');
            app.ClassificationApp.Name = 'Classification App ';
            app.ClassificationApp.KeyPressFcn = createCallbackFcn(app, @ClassificationAppKeyPress, true);
            app.TabGroup = uitabgroup(app.ClassificationApp);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SelectProjectdirButton = uibutton(app.SettingsTab, 'push');
            app.SelectProjectdirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectProjectDirButtonpressed, true);
            app.SelectProjectdirButton.Text = 'Select Project dir';
            app.SelectVideodirButton = uibutton(app.SettingsTab, 'push');
            app.SelectVideodirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVidFun, true);
            app.SelectVideodirButton.Text = 'Select Video dir';
            app.CurrentProjectdirEditFieldLabel = uilabel(app.SettingsTab);
            app.CurrentProjectdirEditFieldLabel.Text = 'Current Project dir';
            app.CurrentProjectdirEditField = uieditfield(app.SettingsTab, 'text');
            app.CurrentProjectdirEditField.Value = 'N/A';
            app.CurrentViddirEditFieldLabel = uilabel(app.SettingsTab);
            app.CurrentViddirEditFieldLabel.Text = 'Current Vid dir';
            app.CurrentViddirEditField = uieditfield(app.SettingsTab, 'text');
            app.CurrentViddirEditField.Value = 'N/A';
            app.BigframestepEditFieldLabel = uilabel(app.SettingsTab);
            app.BigframestepEditFieldLabel.Text = 'Big frame step';
            app.BigframestepEditField = uieditfield(app.SettingsTab, 'numeric');
            app.BigframestepEditField.Limits = [1 5000];
            app.BigframestepEditField.Value = 100;
            app.RandomframestepfromEditFieldLabel = uilabel(app.SettingsTab);
            app.RandomframestepfromEditFieldLabel.Text = 'Random frame step from';
            app.RandomframestepfromEditField = uieditfield(app.SettingsTab, 'numeric');
            app.RandomframestepfromEditField.Limits = [1 5000];
            app.RandomframestepfromEditField.Value = 1000;
            app.toEditFieldLabel = uilabel(app.SettingsTab);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.SettingsTab, 'numeric');
            app.toEditField.Limits = [1 90000];
            app.toEditField.Value = 20000;
            app.FirststackaverageframesfromEditFieldLabel = uilabel(app.SettingsTab);
            app.FirststackaverageframesfromEditFieldLabel.Text = 'First stack average frames from:';
            app.FirststackaverageframesfromEditField = uieditfield(app.SettingsTab, 'numeric');
            app.FirststackaverageframesfromEditField.Limits = [-1000 1000];
            app.FirststackaverageframesfromEditField.Value = -1;
            app.RerunalreadyclassifiedvidsButton = uibutton(app.SettingsTab, 'state');
            app.RerunalreadyclassifiedvidsButton.ValueChangedFcn = createCallbackFcn(app, @RerunalreadyclassifiedvidsButtonValueChanged, true);
            app.RerunalreadyclassifiedvidsButton.Text = 'Rerun already classified vids';
            app.MovecompletedvidsButton = uibutton(app.SettingsTab, 'state');
            app.MovecompletedvidsButton.ValueChangedFcn = createCallbackFcn(app, @MovecompletedvidsButtonValueChanged, true);
            app.MovecompletedvidsButton.Text = 'Move completed vids';
            app.BatchprocessingsettingsLabel = uilabel(app.SettingsTab);
            app.BatchprocessingsettingsLabel.Text = 'Batch processing settings:';
            app.PlotsmoothingfactorframenoEditFieldLabel = uilabel(app.SettingsTab);
            app.PlotsmoothingfactorframenoEditFieldLabel.Text = 'Plot smoothing factor (frame no)';
            app.PlotsmoothingfactorframenoEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PlotsmoothingfactorframenoEditField.Limits = [1 90000];
            app.PlotsmoothingfactorframenoEditField.Value = 200;
            app.ReplotexternalfigureButton = uibutton(app.SettingsTab, 'push');
            app.ReplotexternalfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotexternalfigureButtonPushed, true);
            app.ReplotexternalfigureButton.Text = 'Replot external figure';
            app.ReplotlabelledframemontageButton = uibutton(app.SettingsTab, 'push');
            app.ReplotlabelledframemontageButton.ButtonPushedFcn = createCallbackFcn(app, @ReplotlabelledframemontageButtonPushed, true);
            app.ReplotlabelledframemontageButton.Text = 'Replot labelled frame montage';
            app.PlotlinewidthEditFieldLabel = uilabel(app.SettingsTab);
            app.PlotlinewidthEditFieldLabel.Text = 'Plot linewidth';
            app.PlotlinewidthEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PlotlinewidthEditField.Limits = [0 1000];
            app.PlotlinewidthEditField.Value = 1;
            app.CurrentViddirEditFieldLabel_2 = uilabel(app.SettingsTab);
            app.CurrentViddirEditFieldLabel_2.Text = 'Default video extension (e.g.: .avi; .m4v etc) =';
            app.CurrentViddirEditField_2 = uieditfield(app.SettingsTab, 'text');
            app.CurrentViddirEditField_2.Value = '.m4v';
            app.ThirdstackaverageframesfromEditFieldLabel = uilabel(app.SettingsTab);
            app.ThirdstackaverageframesfromEditFieldLabel.Text = 'Third stack average frames from:';
            app.ThirdstackaverageframesfromEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ThirdstackaverageframesfromEditField.Limits = [-1000 1000];
            app.ThirdstackaverageframesfromEditField.Value = 1;
            app.DuplicatelabelleddatasetwithnewhistoryfutureframesLabel = uilabel(app.SettingsTab);
            app.DuplicatelabelleddatasetwithnewhistoryfutureframesLabel.Text = 'Duplicate labelled dataset with new history/future frames using RGB stack settigns above:';
            app.SelectProjectdirButton_3 = uibutton(app.SettingsTab, 'push');
            app.SelectProjectdirButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectProjectdirButton_3Pushed, true);
            app.SelectProjectdirButton_3.Text = 'Select Project dir';
            app.NewProjectdirEditFieldLabel = uilabel(app.SettingsTab);
            app.NewProjectdirEditFieldLabel.Text = 'New Project dir';
            app.NewProjectdirEditField = uieditfield(app.SettingsTab, 'text');
            app.NewProjectdirEditField.Value = 'N/A';
            app.RunButton = uibutton(app.SettingsTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AddmissingpicstothefoldersButton = uibutton(app.SettingsTab, 'push');
            app.AddmissingpicstothefoldersButton.ButtonPushedFcn = createCallbackFcn(app, @AddmissingpicstothefoldersButtonPushed, true);
            app.AddmissingpicstothefoldersButton.Text = 'Add missing pics to the folders';
            app.EditframefilebasedonfolderspngimagesButton = uibutton(app.SettingsTab, 'push');
            app.EditframefilebasedonfolderspngimagesButton.ButtonPushedFcn = createCallbackFcn(app, @EditframefilebasedonfolderspngimagesButtonPushed, true);
            app.EditframefilebasedonfolderspngimagesButton.Text = 'Edit frame file based on folders/.png images';
            app.LoadvideosintomemoryCheckBox = uicheckbox(app.SettingsTab);
            app.LoadvideosintomemoryCheckBox.Text = 'Load videos into memory';
            app.LoadvideosintomemoryCheckBox.Value = true;
            app.SkipvideosifanerroroccursduringclassificationCheckBox = uicheckbox(app.SettingsTab);
            app.SkipvideosifanerroroccursduringclassificationCheckBox.Text = 'Skip videos if an error occurs during classification';
            app.Resizetonetworksize224x224CheckBox = uicheckbox(app.SettingsTab);
            app.Resizetonetworksize224x224CheckBox.Text = 'Resize to network size (224x224)';
            app.Resizetonetworksize224x224CheckBox.Value = true;
            app.BatchprocessingsettingsLabel_2 = uilabel(app.SettingsTab);
            app.BatchprocessingsettingsLabel_2.Text = 'Suggest to untick these for labelling if full quality of video is desired and tick for classification:';
            app.RGBstackmadeupof3framesthatcanbestacksthemselvesLabel = uilabel(app.SettingsTab);
            app.RGBstackmadeupof3framesthatcanbestacksthemselvesLabel.Text = 'RGB stack made up of 3 frames, that can be stacks themselves:';
            app.SecondstackaverageframesfromEditFieldLabel = uilabel(app.SettingsTab);
            app.SecondstackaverageframesfromEditFieldLabel.Text = 'Second stack average frames from:';
            app.SecondstackaverageframesfromEditField = uieditfield(app.SettingsTab, 'numeric');
            app.SecondstackaverageframesfromEditField.Limits = [-1000 1000];
            app.toEditField_2Label = uilabel(app.SettingsTab);
            app.toEditField_2Label.Text = 'to';
            app.toEditField_2 = uieditfield(app.SettingsTab, 'numeric');
            app.toEditField_2.Limits = [-1000 1000];
            app.toEditField_2.Value = -1;
            app.toEditField_3Label = uilabel(app.SettingsTab);
            app.toEditField_3Label.Text = 'to';
            app.toEditField_3 = uieditfield(app.SettingsTab, 'numeric');
            app.toEditField_3.Limits = [-1000 1000];
            app.toEditField_4Label = uilabel(app.SettingsTab);
            app.toEditField_4Label.Text = 'to';
            app.toEditField_4 = uieditfield(app.SettingsTab, 'numeric');
            app.toEditField_4.Limits = [-1000 1000];
            app.toEditField_4.Value = 1;
            app.DefaultframerotationEditFieldLabel = uilabel(app.SettingsTab);
            app.DefaultframerotationEditFieldLabel.Text = 'Default frame rotation:';
            app.DefaultframerotationEditField = uieditfield(app.SettingsTab, 'numeric');
            app.DefaultframerotationEditField.Limits = [-360 360];
            app.DefaultframerotationEditField.ValueChangedFcn = createCallbackFcn(app, @DefaultframerotationEditFieldValueChanged, true);
            app.DuplicatelabelleddatasetwithnewhistoryfutureframesLabel_2 = uilabel(app.SettingsTab);
            app.DuplicatelabelleddatasetwithnewhistoryfutureframesLabel_2.Text = 'Edit labelled frame folders based on "LabelledFrameInfo.mat" in the project directory: ';
            app.DuplicatelabelleddatasetwithnewhistoryfutureframesLabel_3 = uilabel(app.SettingsTab);
            app.DuplicatelabelleddatasetwithnewhistoryfutureframesLabel_3.Text = 'Edit "LabelledFrameInfo.mat" in the project directory based on  labelled frame folders based pics in the folders:';
            app.LabellingTab = uitab(app.TabGroup);
            app.LabellingTab.Title = 'Labelling';
            app.MainPic = uiaxes(app.LabellingTab);
            title(app.MainPic, '')
            xlabel(app.MainPic, '')
            ylabel(app.MainPic, '')
            app.MainPic.XTick = [];
            app.MainPic.YTick = [];
            app.LAterCurPic = uiaxes(app.LabellingTab);
            title(app.LAterCurPic, 'Cur-Later')
            xlabel(app.LAterCurPic, '')
            ylabel(app.LAterCurPic, '')
            app.LAterCurPic.XTick = [];
            app.LAterCurPic.YTick = [];
            app.PrevCurPiC = uiaxes(app.LabellingTab);
            title(app.PrevCurPiC, 'Cur-Prior')
            xlabel(app.PrevCurPiC, '')
            ylabel(app.PrevCurPiC, '')
            app.PrevCurPiC.XTick = [];
            app.PrevCurPiC.YTick = [];
            app.PrevPic = uiaxes(app.LabellingTab);
            title(app.PrevPic, 'Prior')
            xlabel(app.PrevPic, '')
            ylabel(app.PrevPic, '')
            app.PrevPic.XTick = [];
            app.PrevPic.YTick = [];
            app.CurrPic = uiaxes(app.LabellingTab);
            title(app.CurrPic, 'Current')
            xlabel(app.CurrPic, '')
            ylabel(app.CurrPic, '')
            app.CurrPic.XTick = [];
            app.CurrPic.YTick = [];
            app.LaterPic = uiaxes(app.LabellingTab);
            title(app.LaterPic, 'Later')
            xlabel(app.LaterPic, '')
            ylabel(app.LaterPic, '')
            app.LaterPic.XTick = [];
            app.LaterPic.YTick = [];
            app.UIAxes_pxlchange = uiaxes(app.LabellingTab);
            title(app.UIAxes_pxlchange, '')
            xlabel(app.UIAxes_pxlchange, '')
            ylabel(app.UIAxes_pxlchange, {'Pixel'; 'change'})
            app.UIAxes_pxlchange.XTick = [];
            app.UIAxes_pxlchange.YTick = [];
            app.ClassTable = uitable(app.LabellingTab);
            app.ClassTable.ColumnName = {'Class'; '#vid'; '#/total'};
            app.UndobkspButton = uibutton(app.LabellingTab, 'push');
            app.UndobkspButton.ButtonPushedFcn = createCallbackFcn(app, @UndobkspButtonPushed, true);
            app.UndobkspButton.Text = 'Undo (bksp)';
            app.LabelspaceButton = uibutton(app.LabellingTab, 'push');
            app.LabelspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmLabel, true);
            app.LabelspaceButton.Text = 'Label (space)';
            app.FramesSliderLabel = uilabel(app.LabellingTab);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.LabellingTab);
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @Updateslider, true);
            app.LabelDropDownLabel = uilabel(app.LabellingTab);
            app.LabelDropDownLabel.Text = 'Label:';
            app.LabelDropDown = uidropdown(app.LabellingTab);
            app.LabelDropDown.Items = {'Option 1', 'Option 2'};
            app.LabelDropDown.ValueChangedFcn = createCallbackFcn(app, @Labelpressed, true);
            app.VideoDropDownLabel = uilabel(app.LabellingTab);
            app.VideoDropDownLabel.Text = 'To do vid:';
            app.VideoDropDown = uidropdown(app.LabellingTab);
            app.VideoDropDown.ValueChangedFcn = createCallbackFcn(app, @sendtoloadvid, true);
            app.RotateEditFieldLabel = uilabel(app.LabellingTab);
            app.RotateEditFieldLabel.Text = 'Rotate:';
            app.RotateEditField = uieditfield(app.LabellingTab, 'numeric');
            app.RotateEditField.Limits = [0 359];
            app.RotateEditField.ValueChangedFcn = createCallbackFcn(app, @SetImRot, true);
            app.FrameSpinnerLabel = uilabel(app.LabellingTab);
            app.FrameSpinnerLabel.Text = 'Frame#';
            app.FrameSpinner = uispinner(app.LabellingTab);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.RoundFractionalValues = 'on';
            app.FrameSpinner.ValueDisplayFormat = '%.0f';
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @framenumber, true);
            app.FrameSpinner.Value = 1;
            app.MoveLeftRandom = uibutton(app.LabellingTab, 'push');
            app.MoveLeftRandom.ButtonPushedFcn = createCallbackFcn(app, @RandomLeft, true);
            app.MoveLeftRandom.Text = '<R';
            app.MoveRightRandom = uibutton(app.LabellingTab, 'push');
            app.MoveRightRandom.ButtonPushedFcn = createCallbackFcn(app, @RandomRight, true);
            app.MoveRightRandom.Text = 'R>';
            app.MoveLeft10 = uibutton(app.LabellingTab, 'push');
            app.MoveLeft10.ButtonPushedFcn = createCallbackFcn(app, @Left10, true);
            app.MoveLeft10.Text = '<<';
            app.MoveRight10 = uibutton(app.LabellingTab, 'push');
            app.MoveRight10.ButtonPushedFcn = createCallbackFcn(app, @Right10, true);
            app.MoveRight10.Text = '>>';
            app.LoadvidButton = uibutton(app.LabellingTab, 'push');
            app.LoadvidButton.ButtonPushedFcn = createCallbackFcn(app, @Loadvid, true);
            app.LoadvidButton.Text = 'Load vid';
            app.UseEditFieldLabel = uilabel(app.LabellingTab);
            app.UseEditFieldLabel.Text = 'Use:';
            app.UseEditField = uieditfield(app.LabellingTab, 'text');
            app.UseEditField.Value = 'None';
            app.PriorLabel = uilabel(app.LabellingTab);
            app.PriorLabel.Text = 'Prior';
            app.CurrLabel = uilabel(app.LabellingTab);
            app.CurrLabel.Text = 'Curr.';
            app.LaterLabel = uilabel(app.LabellingTab);
            app.LaterLabel.Text = 'Later';
            app.LabelEditFieldLabel = uilabel(app.LabellingTab);
            app.LabelEditFieldLabel.Text = 'Label:';
            app.LabelEditField = uieditfield(app.LabellingTab, 'text');
            app.LabelEditField.Value = 'None';
            app.PlotPixelChgButton = uibutton(app.LabellingTab, 'state');
            app.PlotPixelChgButton.ValueChangedFcn = createCallbackFcn(app, @plt_pxl_toggle_button, true);
            app.PlotPixelChgButton.Text = 'Plot Pixel Chg?';
            app.LabelEditField_2 = uieditfield(app.LabellingTab, 'text');
            app.LabelEditField_2.Value = 'None';
            app.LabelEditFieldLabel_2 = uilabel(app.LabellingTab);
            app.LabelEditFieldLabel_2.Text = 'Classified label:';
            app.ClassifysingleframeenterButton = uibutton(app.LabellingTab, 'push');
            app.ClassifysingleframeenterButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifySingleFrameFun, true);
            app.ClassifysingleframeenterButton.Text = {'Classify single'; 'frame (enter)'};
            app.LoadnetworkButton = uibutton(app.LabellingTab, 'push');
            app.LoadnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpretrainednetworkButtonPushed, true);
            app.LoadnetworkButton.Text = 'Load network';
            app.ClassifyperframeButton = uibutton(app.LabellingTab, 'state');
            app.ClassifyperframeButton.ValueChangedFcn = createCallbackFcn(app, @ClassifyperframeButtonValueChanged2, true);
            app.ClassifyperframeButton.Text = 'Classify per frame?';
            app.PredictionEditField_2Label = uilabel(app.LabellingTab);
            app.PredictionEditField_2Label.Text = 'Prediction:';
            app.PredictionEditField_2 = uieditfield(app.LabellingTab, 'text');
            app.PredictionEditField_2.Value = 'N/A';
            app.VideoDropDownLabel_2 = uilabel(app.LabellingTab);
            app.VideoDropDownLabel_2.Text = 'Done vid:';
            app.VideoDropDown_3 = uidropdown(app.LabellingTab);
            app.VideoDropDown_3.ValueChangedFcn = createCallbackFcn(app, @VideoDropDown_3ValueChanged, true);
            app.NextvidButton = uibutton(app.LabellingTab, 'push');
            app.NextvidButton.ButtonPushedFcn = createCallbackFcn(app, @NextvidButtonPushed, true);
            app.NextvidButton.Text = {'Next'; 'vid'};
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.SizeChangedFcn = createCallbackFcn(app, @TrainingTabfun, true);
            app.TrainingTab.Title = 'Training';
            app.orchoosenetworkdefaultisresnet50DropDownLabel = uilabel(app.TrainingTab);
            app.orchoosenetworkdefaultisresnet50DropDownLabel.Text = 'or choose network (default is resnet50)';
            app.orchoosenetworkdefaultisresnet50DropDown = uidropdown(app.TrainingTab);
            app.orchoosenetworkdefaultisresnet50DropDown.Items = {'resnet18', 'resnet50', 'resnet101'};
            app.orchoosenetworkdefaultisresnet50DropDown.ValueChangedFcn = createCallbackFcn(app, @NetSelect, true);
            app.orchoosenetworkdefaultisresnet50DropDown.Value = 'resnet50';
            app.TrainButton = uibutton(app.TrainingTab, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNet, true);
            app.TrainButton.Text = 'Train';
            app.Trainvalidateratiodefaultis07EditFieldLabel = uilabel(app.TrainingTab);
            app.Trainvalidateratiodefaultis07EditFieldLabel.Text = 'Train/validate ratio (default is 0.7)';
            app.Trainvalidateratiodefaultis07EditField = uieditfield(app.TrainingTab, 'numeric');
            app.Trainvalidateratiodefaultis07EditField.Limits = [0.01 0.99];
            app.Trainvalidateratiodefaultis07EditField.ValueChangedFcn = createCallbackFcn(app, @trainValRatio, true);
            app.Trainvalidateratiodefaultis07EditField.Value = 0.7;
            app.MiniBatchSizedefaultis10EditFieldLabel = uilabel(app.TrainingTab);
            app.MiniBatchSizedefaultis10EditFieldLabel.Text = 'Mini Batch Size (default is 10)';
            app.MiniBatchSizedefaultis10EditField = uieditfield(app.TrainingTab, 'numeric');
            app.MiniBatchSizedefaultis10EditField.Limits = [1 10000];
            app.MiniBatchSizedefaultis10EditField.ValueChangedFcn = createCallbackFcn(app, @minibatchsizefun, true);
            app.MiniBatchSizedefaultis10EditField.Value = 10;
            app.NumberofEpochsdefaultis20EditFieldLabel = uilabel(app.TrainingTab);
            app.NumberofEpochsdefaultis20EditFieldLabel.Text = 'Number of Epochs (default is 20)';
            app.NumberofEpochsdefaultis20EditField = uieditfield(app.TrainingTab, 'numeric');
            app.NumberofEpochsdefaultis20EditField.Limits = [0 Inf];
            app.NumberofEpochsdefaultis20EditField.ValueChangedFcn = createCallbackFcn(app, @Numofepochsfun, true);
            app.NumberofEpochsdefaultis20EditField.Value = 20;
            app.SaveNetButton = uibutton(app.TrainingTab, 'push');
            app.SaveNetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMyNetFun, true);
            app.SaveNetButton.Text = 'Save Net';
            app.NamethetrainednetworkEditFieldLabel = uilabel(app.TrainingTab);
            app.NamethetrainednetworkEditFieldLabel.Text = 'Name the trained network';
            app.NamethetrainednetworkEditField = uieditfield(app.TrainingTab, 'text');
            app.NamethetrainednetworkEditField.ValueChangedFcn = createCallbackFcn(app, @MyNetNameFun, true);
            app.NamethetrainednetworkEditField.Value = 'mynet';
            app.SelectProjectdirButton_2 = uibutton(app.TrainingTab, 'push');
            app.SelectProjectdirButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectProjectDirButtonpressed, true);
            app.SelectProjectdirButton_2.Text = 'Select Project dir';
            app.OptionalselectdirfornetButton = uibutton(app.TrainingTab, 'push');
            app.OptionalselectdirfornetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdirforNet, true);
            app.OptionalselectdirfornetButton.Text = '(Optional) select dir for net';
            app.FinalaccuracyEditFieldLabel = uilabel(app.TrainingTab);
            app.FinalaccuracyEditFieldLabel.Text = 'Final accuracy %';
            app.FinalaccuracyEditField = uieditfield(app.TrainingTab, 'numeric');
            app.LoadpretrainednetworkButton = uibutton(app.TrainingTab, 'push');
            app.LoadpretrainednetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpretrainednetworkButtonPushed, true);
            app.LoadpretrainednetworkButton.Text = 'Load pre-trained network';
            app.NameoftheselectednetEditField_2Label = uilabel(app.TrainingTab);
            app.NameoftheselectednetEditField_2Label.Text = 'Name of the selected net';
            app.NameoftheselectednetEditField = uieditfield(app.TrainingTab, 'text');
            app.NameoftheselectednetEditField.Value = 'None';
            app.ReplacefinalLayerEditField = uieditfield(app.TrainingTab, 'text');
            app.ReplacefinalLayerEditField.Value = 'N/A';
            app.FromlayerLabel = uilabel(app.TrainingTab);
            app.FromlayerLabel.Text = 'From layer:';
            app.FromlayerEditField = uieditfield(app.TrainingTab, 'numeric');
            app.FromlayerEditField.Limits = [1 10000];
            app.FromlayerEditField.ValueChangedFcn = createCallbackFcn(app, @FromlayerEditFieldValueChanged, true);
            app.FromlayerEditField.Value = 1;
            app.tolayerEditFieldLabel = uilabel(app.TrainingTab);
            app.tolayerEditFieldLabel.Text = 'to layer:';
            app.tolayerEditField = uieditfield(app.TrainingTab, 'numeric');
            app.tolayerEditField.Limits = [1 10000];
            app.tolayerEditField.ValueChangedFcn = createCallbackFcn(app, @tolayerEditFieldValueChanged, true);
            app.tolayerEditField.Value = 10;
            app.FreezeinitiallayersButton = uibutton(app.TrainingTab, 'state');
            app.FreezeinitiallayersButton.ValueChangedFcn = createCallbackFcn(app, @FreezeinitiallayersButtonValueChanged, true);
            app.FreezeinitiallayersButton.Text = 'Freeze initial layers?';
            app.ReplacefinalLayerButton = uibutton(app.TrainingTab, 'state');
            app.ReplacefinalLayerButton.ValueChangedFcn = createCallbackFcn(app, @ReplacefinalLayerButtonValueChanged, true);
            app.ReplacefinalLayerButton.Text = 'Replace final Layer?';
            app.BalanceclassesRecommendedifnumebrsofframesdifferalotCheckBox = uicheckbox(app.TrainingTab);
            app.BalanceclassesRecommendedifnumebrsofframesdifferalotCheckBox.Text = 'Balance classes (Recommended if numebrs of frames differ a lot)';
            app.BalanceclassesRecommendedifnumebrsofframesdifferalotCheckBox.Value = true;
            app.ChecksettingsoutputinMatlabCommandWindowButton = uibutton(app.TrainingTab, 'push');
            app.ChecksettingsoutputinMatlabCommandWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ChecksettingsoutputinMatlabCommandWindowButtonPushed, true);
            app.ChecksettingsoutputinMatlabCommandWindowButton.Text = {'Check settings'; '(output in Matlab Command Window)'};
            app.NumberofaugmentedimageplotsEditFieldLabel = uilabel(app.TrainingTab);
            app.NumberofaugmentedimageplotsEditFieldLabel.Text = 'Number of  augmented image plots';
            app.NumberofaugmentedimageplotsEditField = uieditfield(app.TrainingTab, 'numeric');
            app.NumberofaugmentedimageplotsEditField.Limits = [1 10000];
            app.NumberofaugmentedimageplotsEditField.Value = 40;
            app.AssesstrainednetButton = uibutton(app.TrainingTab, 'push');
            app.AssesstrainednetButton.ButtonPushedFcn = createCallbackFcn(app, @AssesstrainednetButtonPushed, true);
            app.AssesstrainednetButton.Text = 'Assess trained net';
            app.ClassifyingTab = uitab(app.TabGroup);
            app.ClassifyingTab.Title = 'Classifying';
            app.UIAxes = uiaxes(app.ClassifyingTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Certainty %')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.ClassifyingTab);
            title(app.UIAxes_2, 'Raw Classification')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, 'Certainty %')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.MainPic_2 = uiaxes(app.ClassifyingTab);
            title(app.MainPic_2, '')
            xlabel(app.MainPic_2, '')
            ylabel(app.MainPic_2, '')
            app.MainPic_2.XTick = [];
            app.MainPic_2.YTick = [];
            app.UIAxes_pxlchange_2 = uiaxes(app.ClassifyingTab);
            title(app.UIAxes_pxlchange_2, '')
            xlabel(app.UIAxes_pxlchange_2, '')
            ylabel(app.UIAxes_pxlchange_2, 'Pixel change')
            app.UIAxes_pxlchange_2.XTick = [];
            app.UIAxes_pxlchange_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.ClassifyingTab);
            title(app.UIAxes_3, 'Smoothed classification')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, 'Certainty %')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.LoadpretrainednetButton_2 = uibutton(app.ClassifyingTab, 'push');
            app.LoadpretrainednetButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadMyNetFun, true);
            app.LoadpretrainednetButton_2.Text = 'Load pretrained net';
            app.SelectVideodirButton_2 = uibutton(app.ClassifyingTab, 'push');
            app.SelectVideodirButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectVidFun, true);
            app.SelectVideodirButton_2.Text = 'Select Video dir';
            app.VideoDropDown_2Label = uilabel(app.ClassifyingTab);
            app.VideoDropDown_2Label.Text = 'Video';
            app.VideoDropDown_2 = uidropdown(app.ClassifyingTab);
            app.VideoDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VideoDropDown_2ValueChanged, true);
            app.FramesSlider_2Label = uilabel(app.ClassifyingTab);
            app.FramesSlider_2Label.Text = 'Frames';
            app.FramesSlider_2 = uislider(app.ClassifyingTab);
            app.FramesSlider_2.ValueChangedFcn = createCallbackFcn(app, @Updateslider2, true);
            app.LoadvidButton_2 = uibutton(app.ClassifyingTab, 'push');
            app.LoadvidButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadvidButton_2Pushed, true);
            app.LoadvidButton_2.Text = 'Load vid';
            app.MoveLeftRandom_2 = uibutton(app.ClassifyingTab, 'push');
            app.MoveLeftRandom_2.ButtonPushedFcn = createCallbackFcn(app, @RandomLeft, true);
            app.MoveLeftRandom_2.Text = '<R';
            app.MoveRightRandom_2 = uibutton(app.ClassifyingTab, 'push');
            app.MoveRightRandom_2.ButtonPushedFcn = createCallbackFcn(app, @RandomRight, true);
            app.MoveRightRandom_2.Text = 'R>';
            app.MoveLeft10_2 = uibutton(app.ClassifyingTab, 'push');
            app.MoveLeft10_2.ButtonPushedFcn = createCallbackFcn(app, @Left10, true);
            app.MoveLeft10_2.Text = '<<';
            app.MoveRight10_2 = uibutton(app.ClassifyingTab, 'push');
            app.MoveRight10_2.ButtonPushedFcn = createCallbackFcn(app, @Right10, true);
            app.MoveRight10_2.Text = '>>';
            app.FrameSpinner_2Label = uilabel(app.ClassifyingTab);
            app.FrameSpinner_2Label.Text = 'Frame#';
            app.FrameSpinner_2 = uispinner(app.ClassifyingTab);
            app.FrameSpinner_2.Limits = [1 Inf];
            app.FrameSpinner_2.RoundFractionalValues = 'on';
            app.FrameSpinner_2.ValueDisplayFormat = '%.0f';
            app.FrameSpinner_2.ValueChangedFcn = createCallbackFcn(app, @framenumber2, true);
            app.FrameSpinner_2.Value = 1;
            app.ClassifysingleframespaceButton = uibutton(app.ClassifyingTab, 'push');
            app.ClassifysingleframespaceButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifySingleFrameFun, true);
            app.ClassifysingleframespaceButton.Text = 'Classify single frame (space)';
            app.ClassifywholevidButton = uibutton(app.ClassifyingTab, 'push');
            app.ClassifywholevidButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyVid, true);
            app.ClassifywholevidButton.Text = 'Classify whole vid';
            app.RotateEditField_2Label = uilabel(app.ClassifyingTab);
            app.RotateEditField_2Label.Text = 'Rotate';
            app.RotateEditField_2 = uieditfield(app.ClassifyingTab, 'numeric');
            app.RotateEditField_2.Limits = [0 359];
            app.RotateEditField_2.ValueChangedFcn = createCallbackFcn(app, @SetImRot2, true);
            app.PredictionEditFieldLabel = uilabel(app.ClassifyingTab);
            app.PredictionEditFieldLabel.Text = 'Prediction:';
            app.PredictionEditField = uieditfield(app.ClassifyingTab, 'text');
            app.PredictionEditField.Value = 'N/A';
            app.CertaintyEditFieldLabel = uilabel(app.ClassifyingTab);
            app.CertaintyEditFieldLabel.Text = 'Certainty % =';
            app.CertaintyEditField = uieditfield(app.ClassifyingTab, 'numeric');
            app.ClassifyallvidsinthedirButton = uibutton(app.ClassifyingTab, 'push');
            app.ClassifyallvidsinthedirButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyallvidsinthedirButtonPushed, true);
            app.ClassifyallvidsinthedirButton.Text = 'Classify all vids in the dir';
            app.ClassifyvidswithspecifiedframesButton = uibutton(app.ClassifyingTab, 'push');
            app.ClassifyvidswithspecifiedframesButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyvidswithspecifiedframesButtonPushed, true);
            app.ClassifyvidswithspecifiedframesButton.Text = 'Classify vids with specified frames';
            app.Makeselect_framesButton = uibutton(app.ClassifyingTab, 'push');
            app.Makeselect_framesButton.ButtonPushedFcn = createCallbackFcn(app, @Makeselect_framesButtonPushed, true);
            app.Makeselect_framesButton.Text = 'Make "select_frames"';
            app.BatchnumEditFieldLabel = uilabel(app.ClassifyingTab);
            app.BatchnumEditFieldLabel.Text = 'Batch num:';
            app.BatchnumEditField = uieditfield(app.ClassifyingTab, 'numeric');
            app.BatchnumEditField.Limits = [0 359];
            app.BatchnumEditField.Value = 10;
            app.EverynthframeEditFieldLabel = uilabel(app.ClassifyingTab);
            app.EverynthframeEditFieldLabel.Text = 'Every nth frame';
            app.EverynthframeEditField = uieditfield(app.ClassifyingTab, 'numeric');
            app.EverynthframeEditField.Limits = [0 359];
            app.EverynthframeEditField.Value = 1;
            app.InspectexamplesTab = uitab(app.TabGroup);
            app.InspectexamplesTab.Title = 'Inspect examples';
            app.MainPic_5 = uiaxes(app.InspectexamplesTab);
            title(app.MainPic_5, '')
            xlabel(app.MainPic_5, '')
            ylabel(app.MainPic_5, '')
            app.MainPic_5.XTick = [];
            app.MainPic_5.YTick = [];
            app.ChooseaclassDropDownLabel = uilabel(app.InspectexamplesTab);
            app.ChooseaclassDropDownLabel.Text = 'Choose a class';
            app.ChooseaclassDropDown = uidropdown(app.InspectexamplesTab);
            app.ChooseaclassDropDown.Items = {'Option 1', 'Option 2'};
            app.ChooseaclassDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseaclassDropDownValueChanged, true);
            app.CertaintythresholdEditFieldLabel = uilabel(app.InspectexamplesTab);
            app.CertaintythresholdEditFieldLabel.Text = 'Certainty threshold %:';
            app.CertaintythresholdEditField = uieditfield(app.InspectexamplesTab, 'numeric');
            app.CertaintythresholdEditField.Limits = [0 100];
            app.CertaintythresholdEditField.Value = 70;
            app.ExampleSpinnerLabel = uilabel(app.InspectexamplesTab);
            app.ExampleSpinnerLabel.Text = 'Example #:';
            app.ExampleSpinner = uispinner(app.InspectexamplesTab);
            app.ExampleSpinner.Limits = [1 Inf];
            app.ExampleSpinner.RoundFractionalValues = 'on';
            app.ExampleSpinner.ValueDisplayFormat = '%.0f';
            app.ExampleSpinner.ValueChangedFcn = createCallbackFcn(app, @ExampleSpinnerValueChanged, true);
            app.ExampleSpinner.Value = 1;
            app.ExampleframesfoundEditFieldLabel = uilabel(app.InspectexamplesTab);
            app.ExampleframesfoundEditFieldLabel.Text = 'Example frames found:';
            app.ExampleframesfoundEditField = uieditfield(app.InspectexamplesTab, 'numeric');
            app.ExampleframesfoundEditField.Limits = [0 Inf];
            app.Label = uilabel(app.InspectexamplesTab);
            app.Label.Text = {'Here most confident examples of classes in the classified videos can be inspected. '; 'They can then be relabelled in the "Labelling" tab if required.'; '1) Choose a class you want to inspect.'; '2) Specify a certainty threshold.'; '3) Hit find examples.'; '4) Examples will be displayed in the decreasing order of confidence.'};
            app.FindexamplesButton = uibutton(app.InspectexamplesTab, 'push');
            app.FindexamplesButton.ButtonPushedFcn = createCallbackFcn(app, @FindexamplesButtonPushed, true);
            app.FindexamplesButton.Text = 'Find examples';
            app.FromvidsEditFieldLabel = uilabel(app.InspectexamplesTab);
            app.FromvidsEditFieldLabel.Text = 'From # vids:';
            app.FromvidsEditField = uieditfield(app.InspectexamplesTab, 'numeric');
            app.FromvidsEditField.Limits = [0 Inf];
            app.VideoDropDownLabel_3 = uilabel(app.InspectexamplesTab);
            app.VideoDropDownLabel_3.Text = 'To do vid:';
            app.VideoDropDown_4 = uidropdown(app.InspectexamplesTab);
            app.LoadvidButton_3 = uibutton(app.InspectexamplesTab, 'push');
            app.LoadvidButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadvidButton_3Pushed, true);
            app.LoadvidButton_3.Text = 'Load vid';
            app.FrameSpinner_3Label = uilabel(app.InspectexamplesTab);
            app.FrameSpinner_3Label.Text = 'Frame#';
            app.FrameSpinner_3 = uispinner(app.InspectexamplesTab);
            app.FrameSpinner_3.Limits = [1 Inf];
            app.FrameSpinner_3.RoundFractionalValues = 'on';
            app.FrameSpinner_3.ValueDisplayFormat = '%.0f';
            app.FrameSpinner_3.ValueChangedFcn = createCallbackFcn(app, @FrameSpinner_3ValueChanged, true);
            app.FrameSpinner_3.Value = 1;
            app.ExamplesinthisvidEditFieldLabel = uilabel(app.InspectexamplesTab);
            app.ExamplesinthisvidEditFieldLabel.Text = 'Examples in this vid:';
            app.ExamplesinthisvidEditField = uieditfield(app.InspectexamplesTab, 'numeric');
            app.ExamplesinthisvidEditField.Limits = [0 Inf];
            app.DeepDreamTab = uitab(app.TabGroup);
            app.DeepDreamTab.Title = 'DeepDream';
            app.MainPic_3 = uiaxes(app.DeepDreamTab);
            title(app.MainPic_3, '')
            xlabel(app.MainPic_3, '')
            ylabel(app.MainPic_3, '')
            app.MainPic_3.XTick = [];
            app.MainPic_3.YTick = [];
            app.LoadpretrainednetButton_3 = uibutton(app.DeepDreamTab, 'push');
            app.LoadpretrainednetButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadpretrainednetButton_3Pushed, true);
            app.LoadpretrainednetButton_3.Text = 'Load pretrained net';
            app.AnalysenetworkButton = uibutton(app.DeepDreamTab, 'push');
            app.AnalysenetworkButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysenetworkButtonPushed, true);
            app.AnalysenetworkButton.Text = 'Analyse network';
            app.SelectlastfullyconnectedlayerSuggestedEditFieldLabel = uilabel(app.DeepDreamTab);
            app.SelectlastfullyconnectedlayerSuggestedEditFieldLabel.Text = 'Select last fully connected layer. Suggested = ';
            app.SelectlastfullyconnectedlayerSuggestedEditField = uieditfield(app.DeepDreamTab, 'numeric');
            app.LabelDropDown_2Label = uilabel(app.DeepDreamTab);
            app.LabelDropDown_2Label.Text = 'Label:';
            app.LabelDropDown_2 = uidropdown(app.DeepDreamTab);
            app.LabelDropDown_2.Items = {'Option 1', 'Option 2'};
            app.LabelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @LabelDropDown_2ValueChanged, true);
            app.NumberofiterationsEditFieldLabel = uilabel(app.DeepDreamTab);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations  = ';
            app.NumberofiterationsEditField = uieditfield(app.DeepDreamTab, 'numeric');
            app.NumberofiterationsEditField.Value = 100;
            app.DreamButton = uibutton(app.DeepDreamTab, 'push');
            app.DreamButton.ButtonPushedFcn = createCallbackFcn(app, @DreamButtonPushed, true);
            app.DreamButton.Text = 'Dream';
            app.OptionalselectdirsavingthedreamButton = uibutton(app.DeepDreamTab, 'push');
            app.OptionalselectdirsavingthedreamButton.ButtonPushedFcn = createCallbackFcn(app, @OptionalselectdirsavingthedreamButtonPushed, true);
            app.OptionalselectdirsavingthedreamButton.Text = '(Optional) select dir saving the dream';
            app.NamethedreamEditFieldLabel = uilabel(app.DeepDreamTab);
            app.NamethedreamEditFieldLabel.Text = 'Name the dream';
            app.NamethedreamEditField = uieditfield(app.DeepDreamTab, 'text');
            app.NamethedreamEditField.Value = 'DeepDream';
            app.SaveDreamButton = uibutton(app.DeepDreamTab, 'push');
            app.SaveDreamButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDreamButtonPushed, true);
            app.SaveDreamButton.Text = 'Save Dream';
            app.HumanvsNetTab = uitab(app.TabGroup);
            app.HumanvsNetTab.Title = 'Human vs Net';
            app.MainPic_4 = uiaxes(app.HumanvsNetTab);
            title(app.MainPic_4, '')
            xlabel(app.MainPic_4, '')
            ylabel(app.MainPic_4, '')
            app.MainPic_4.XTick = [];
            app.MainPic_4.YTick = [];
            app.NextFrame_HvN = uibutton(app.HumanvsNetTab, 'push');
            app.NextFrame_HvN.ButtonPushedFcn = createCallbackFcn(app, @NextFrame_HvNButtonPushed, true);
            app.NextFrame_HvN.Text = '>>';
            app.LabelsListBoxLabel = uilabel(app.HumanvsNetTab);
            app.LabelsListBoxLabel.Text = 'Labels:';
            app.LabelsListBox = uilistbox(app.HumanvsNetTab);
            app.LabelsListBox.ValueChangedFcn = createCallbackFcn(app, @LabelsListBoxValueChanged, true);
            app.GeneratearandomlistofframesButton = uibutton(app.HumanvsNetTab, 'push');
            app.GeneratearandomlistofframesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratearandomlistofframesButtonPushed, true);
            app.GeneratearandomlistofframesButton.Text = 'Generate a random list of frames';
            app.TesttheNetButton = uibutton(app.HumanvsNetTab, 'push');
            app.TesttheNetButton.ButtonPushedFcn = createCallbackFcn(app, @TesttheNetButtonPushed, true);
            app.TesttheNetButton.Text = 'Test the Net';
            app.InfoTextAreaLabel = uilabel(app.HumanvsNetTab);
            app.InfoTextAreaLabel.Text = 'Info:';
            app.InfoTextArea = uitextarea(app.HumanvsNetTab);
            app.InfoTextArea.Value = {'Please select directory with classes and labelled frames in prior tabs first. To test a pretrained net, please load it also. 1 random dataset is genertated and can be tested multiple times.'};
            app.IDEditFieldLabel = uilabel(app.HumanvsNetTab);
            app.IDEditFieldLabel.Text = 'ID:';
            app.IDEditField = uieditfield(app.HumanvsNetTab, 'text');
            app.Start_HvN = uibutton(app.HumanvsNetTab, 'push');
            app.Start_HvN.ButtonPushedFcn = createCallbackFcn(app, @Start_HvNButtonPushed, true);
            app.Start_HvN.Text = 'Start';
            app.offramestotestEditFieldLabel = uilabel(app.HumanvsNetTab);
            app.offramestotestEditFieldLabel.Text = '# of frames to test:';
            app.offramestotestEditField = uieditfield(app.HumanvsNetTab, 'numeric');
            app.offramestotestEditField.Value = 100;
            app.UITable = uitable(app.HumanvsNetTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ResultstableButton = uibutton(app.HumanvsNetTab, 'push');
            app.ResultstableButton.ButtonPushedFcn = createCallbackFcn(app, @ResultstableButtonPushed, true);
            app.ResultstableButton.Text = 'Results table';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.InstallinfoTextAreaLabel = uilabel(app.HelpTab);
            app.InstallinfoTextAreaLabel.Text = 'Install info:';
            app.InstallinfoTextArea = uitextarea(app.HelpTab);
            app.InstallinfoTextArea.Value = {'From Matlab "Get More Apps" tab download and install:'; '- Image Processing Toolbox '; '- Deep Learning Toolbox'; '- (optional) To use drop down network selection (in training tab) get: resnet18/resnet50/resnet101.'; '- (optional) To use GPU for network training get: Parallel Computing Toolbox'; ''; 'Get network training functions from Google net example by typing into the matlab command line:'; '"openExample(''nnet/TransferLearningUsingGoogLeNetExample'')"'; ''; 'Add matlab path to the google net example by going to Home tab --> Set path --> Add with Subfolders.'; ''; 'Credit:'; '- Training dataset balancing functions (https://uk.mathworks.com/matlabcentral/fileexchange/78020-oversampling-for-deep-learning-classification-example)'};
            app.WorkflowTextAreaLabel = uilabel(app.HelpTab);
            app.WorkflowTextAreaLabel.Text = 'Workflow:';
            app.WorkflowTextArea = uitextarea(app.HelpTab);
            app.WorkflowTextArea.Value = {'1) Create a project folder with subfolders that have names of desired classes.'; '2) In the Settings tab, locate project and video folders. Make sure vid file extension is correct.'; '3) In the Labelling tab, load selected "To do" video and label desired frames.*'; '4) In Training tab select resnet from dropdown (requires installation) or load your own network.**'; '5) Train network. After training is completed, retrain or name and save the network.'; '6) Use existing or re-laod a network in the Classifying tab. '; '7) Test net accuracy by classifying a few frames.'; '8) Classify a single video or batch entire folder content. '; '9) Review in Labelling tab by loading "Done" videos. If unsatisied retrain existing network or train a new one.'; ''; '* Keyboard commands only work if no figure objects are selected, thus mouse left hit some grey area of the GUI.'; '** Selecting net from dropdown will replace final layer with your classes, whereas same layer will be used if loading from file. This can be changed by pressing the "Replace final Layer?" button. '};
            app.TipstricksTextAreaLabel = uilabel(app.HelpTab);
            app.TipstricksTextAreaLabel.Text = 'Tips & tricks:';
            app.TipstricksTextArea = uitextarea(app.HelpTab);
            app.TipstricksTextArea.Value = {'- Check that video frame indexes are correct. Simple ways to do are 1) load video into VLC and see if an error comes up 2) load a video into matlab VideoReader() and see if correct number of frames are seen. If indexes are wrong, can use handbrake (recommended), ffmpeg or VLC.'; '- Avoid using spaces and full stops in all file names'};
            app.KeycommandsTextAreaLabel = uilabel(app.HelpTab);
            app.KeycommandsTextAreaLabel.Text = {'Key'; 'commands:'};
            app.KeycommandsTextArea = uitextarea(app.HelpTab);
            app.KeycommandsTextArea.Value = {'Labelling:'; 'Single frame backwards: ''a'' / ''←''. Single frame forward: ''d'' / ''→''.Big step back: ''s'' / ''↓''. Big step forward: ''w'' / ''↑''. '; 'Random frame number backwards: ''q'' / ''n''.  Random frame number fowards: ''e'' / ''m''. '; 'Label frame: ''Space''. Undo latest label: ''Backspace''. '; 'Numbers 1-9 select defined classes 1-9.'; ''; 'Review:'; 'If network is loaded, classify single frame: ''Enter''.'; ''; 'Classifying:'; 'If network is loaded, classify  single frame: ''Space''/''Enter''.'; ''};
            app.StatusEditFieldLabel = uilabel(app.ClassificationApp);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.ClassificationApp, 'text');
            app.StatusEditField.Value = 'App starting';
            % Show the figure after all components are created
            app.ClassificationApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VidClassNetTrain_23122021a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClassificationApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClassificationApp)
        end
    end
end